classdef DAST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GaindBSpinner                 matlab.ui.control.Spinner
        GaindBSpinnerLabel            matlab.ui.control.Label
        UploadedPresetEditField       matlab.ui.control.EditField
        LoadPresetButton              matlab.ui.control.Button
        SettingsButton                matlab.ui.control.Button
        Instructions                  matlab.ui.control.Label
        ProgressPanel                 matlab.ui.container.Panel
        CurrentBlock                  matlab.ui.control.Label
        SentIdxVsSNR                  matlab.ui.control.UIAxes
        SNRVsPercent                  matlab.ui.control.UIAxes
        NextButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.StateButton
        DASTpreprocHLCM1Label         matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        ScoringPanel                  matlab.ui.container.Panel
        W15Button                     matlab.ui.control.StateButton
        W14Button                     matlab.ui.control.StateButton
        W13Button                     matlab.ui.control.StateButton
        CurrentSent                   matlab.ui.control.Label
        W12Button                     matlab.ui.control.StateButton
        W11Button                     matlab.ui.control.StateButton
        W10Button                     matlab.ui.control.StateButton
        W9Button                      matlab.ui.control.StateButton
        W8Button                      matlab.ui.control.StateButton
        W7Button                      matlab.ui.control.StateButton
        W6Button                      matlab.ui.control.StateButton
        W5Button                      matlab.ui.control.StateButton
        W4Button                      matlab.ui.control.StateButton
        W3Button                      matlab.ui.control.StateButton
        W2Button                      matlab.ui.control.StateButton
        W1Button                      matlab.ui.control.StateButton
        PlayButton                    matlab.ui.control.Button
        MarkallButton                 matlab.ui.control.StateButton
        ContinueButton                matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        AudioSettingsTabs             matlab.ui.container.TabGroup
        QuickstartTab                 matlab.ui.container.Tab
        ModeDropDownInQuickStartTab   matlab.ui.control.DropDown
        ModeDropDown_2Label           matlab.ui.control.Label
        CorpusDropDown                matlab.ui.control.DropDown
        CorpusDropDownLabel           matlab.ui.control.Label
        ListTypeDropDown              matlab.ui.control.DropDown
        ListtypeDropDownLabel         matlab.ui.control.Label
        StartingMaskerLevelEditField  matlab.ui.control.NumericEditField
        StartingmaskerlevelLabel      matlab.ui.control.Label
        StartingTargetLevelEditField  matlab.ui.control.NumericEditField
        TargetlevelLabel              matlab.ui.control.Label
        MaskerDropDown                matlab.ui.control.DropDown
        MaskerLabel                   matlab.ui.control.Label
        ListNumberDropDown            matlab.ui.control.DropDown
        ListnumberDropDownLabel       matlab.ui.control.Label
        TargetDropDown                matlab.ui.control.DropDown
        TargetDropDownLabel           matlab.ui.control.Label
        dBSPLLabel_2                  matlab.ui.control.Label
        dBSPLLabel                    matlab.ui.control.Label
        AdvancedTab                   matlab.ui.container.Tab
        ModeDropDownInAdvanced        matlab.ui.control.DropDown
        ModeDropDownLabel             matlab.ui.control.Label
        dBLabel_2                     matlab.ui.control.Label
        SRTInQuietEditField           matlab.ui.control.NumericEditField
        SRTinquietEditFieldLabel      matlab.ui.control.Label
        StartingBlockDropDown         matlab.ui.control.DropDown
        StartingblockDropDownLabel    matlab.ui.control.Label
        UploadedCSVCheckBox           matlab.ui.control.CheckBox
        CSVFileEditField              matlab.ui.control.EditField
        CSVfileEditFieldLabel         matlab.ui.control.Label
        UploadButton                  matlab.ui.control.Button
        BasicInformationPanel         matlab.ui.container.Panel
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        ParticipantIDEditField        matlab.ui.control.EditField
        ParticipantIDEditFieldLabel   matlab.ui.control.Label
        TesterIDEditField             matlab.ui.control.EditField
        TesterIDEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = public)

        % Definition of global variables

        % Everything that gets saved in result file (i.e., DATA)
        data

        % Everything that doesnt get saved but persists throughout the session (i.e., PERSISTENT)
        pers

        % Everything that changes from sentence to sentence (i.e., ITERATION)
        iter

    end

    properties (Access = private)

        SettingsApp % to call settings window

    end

    % Functions that are accessible outside this app
    methods (Access = public)

        function transferSettings(app,dataStruct,persStruct)

            % Store transfered data from SettingsApp
            app.data = dataStruct;
            app.pers = persStruct;
            app.UploadedPresetEditField.Value = app.pers.UploadedPreset;

            % Disable SRT value input if compensation is enabled in the settings
            if app.data.Settings.ApplyHearingLossCompensation == 1
                app.SRTInQuietEditField.Enable = "off";
                app.SRTinquietEditFieldLabel.Enable = "off";
                app.dBLabel_2.Enable = "off";
            else
                app.SRTInQuietEditField.Enable = "on";
                app.SRTinquietEditFieldLabel.Enable = "on";
                app.dBLabel_2.Enable = "on";
            end

        end

        function persStruct = initializePlaybackObjects(app)

            % Setting up playback method
            switch app.data.Settings.PlaybackMethod

                case 'Basic'

                    % No setup needed

                case 'AudioToolbox' % Matlab Audio Toolbox

                    % Check if dsp object already exists
                    if ~isempty(app.pers.playbackObjectPlayer)
                        release(app.pers.playbackObjectPlayer);
                    end
                    if ~isempty(app.pers.playbackObjectRecorder)
                        release(app.pers.playbackObjectRecorder);
                    end

                    % Create the handle to the device writer
                    if contains(app.data.Settings.PlaybackDevice,'No audio output','IgnoreCase',true) % if no device name is recognized
                        if ~isempty(app.data.Settings.PlaybackChannelsTarget)
                            app.pers.playbackObjectPlayer = audioDeviceWriter(app.data.Settings.Fs,...
                                'Driver',app.data.Settings.PlaybackDriver,...
                                'ChannelMappingSource','Property',...
                                'ChannelMapping',unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]),...
                                'BufferSize',app.data.Settings.PlaybackBuffer);
                        end
                    else
                        if ~isempty(app.data.Settings.PlaybackChannelsTarget)
                            app.pers.playbackObjectPlayer = audioDeviceWriter(app.data.Settings.Fs,...
                                'Driver',app.data.Settings.PlaybackDriver,...
                                'DeviceName',app.data.Settings.PlaybackDevice,...
                                'ChannelMappingSource','Property',...
                                'ChannelMapping',unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]),...
                                'BufferSize',app.data.Settings.PlaybackBuffer);
                        end
                    end
                    % Create the handle to the device recorder, if relevant
                    if ~isempty(app.data.Settings.PlaybackChannelsMic)
                        app.pers.playbackObjectRecorder = audioDeviceReader(app.data.Settings.Fs,...
                            'Driver',app.data.Settings.PlaybackDriver,...
                            'DeviceName',app.data.Settings.PlaybackDevice,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.data.Settings.PlaybackChannelsMic,...
                            'SamplesPerFrame',app.data.Settings.PlaybackBuffer);
                    end

                case 'Playrec'

                    % Add playrec to path
                    if ~isempty(app.data.Settings.PlaybackMethodInstallPath)
                        addpath(app.data.Settings.PlaybackMethodInstallPath);
                    end

                    % Create the handle
                    HeaAudioPlayRec(...
                        'fs',app.data.Settings.Fs,...
                        'channelsPlay',unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]),...
                        'channelsRec',app.data.Settings.PlaybackChannelsMic,...
                        'devicePlayID',app.data.Settings.PlaybackDriver);

                    if ~isempty(app.data.Settings.PlaybackChannelsMic)
                        error('The recording functionality is currently only implemented using the Audio Toolbox.');
                    end

                case 'SoundMexPro'

                    % Initialize
                    clear soundmexpro;
                    listOfChannels = unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]);
                    if app.data.Settings.MaskerSettings == "Continuous"
                        trackMultiplier = 2;
                    else
                        trackMultiplier = 1;
                    end
                    soundmexpro('init', ...
                        'driver', app.data.Settings.PlaybackDevice, ...
                        'samplerate', app.data.Settings.Fs, ...
                        'numbufs', 10, ... % software buffering to avoid drops (see SoundMexPro documentation for more info)
                        'output', listOfChannels-1, ... % zero base
                        'track', trackMultiplier*numel(listOfChannels), ... % uses default 'circular' mapping
                        'autocleardata',1, ... % all audio data segments that were already played completely are freed from memory on every 'loadfile' or 'loadmem' command
                        'quiet', 1 ...
                        );

            end

            % Send app.pers to output
            persStruct = app.pers;

        end

        function persStruct = releaseAudioObjs(app,persStruct)

            % Process input
            if nargin > 1
                app.pers = persStruct;
            end

            % Release all existing audio objects
            app.pers.recordingFlag = false;
            app.pers.playingFlag = false;
            app.pers.maskerPlayingFlag = false;
            app.pers.maskerPlayingIdx = [];
            switch app.data.Settings.PlaybackMethod
                case 'Basic'
                    clear sound;
                case 'AudioToolbox' % Matlab Audio Toolbox
                    if isfield(app.data.Settings,'playbackObjectPlayer') && ~isempty(app.pers.playbackObjectPlayer)
                        release(app.pers.playbackObjectPlayer);
                    end
                    if isfield(app.data.Settings,'playbackObjectRecorder') && ~isempty(app.pers.playbackObjectRecorder)
                        release(app.pers.playbackObjectRecorder);
                    end
                    if isfield(app.data.Settings,'playbackObjectFileWriter') && ~isempty(app.pers.playbackObjectFileWriter)
                        release(app.pers.playbackObjectFileWriter);
                    end
                case 'Playrec'
                    % TODO
                case 'SoundMexPro'
                    soundmexpro('stop');
            end

            persStruct = app.pers;

        end

        function signal = applyEqualizationFilter(app,signal,whichChannels) % note size(signal,2) should equal size(whichChannels,2)

            % Load equalization filter, if relevant
            if ~isempty(app.data.Settings.EqualizationFilterPath)

                if ~isfield(app.pers,'equalizationFilter') || isempty(app.pers.equalizationFilter)
                    load(app.data.Settings.EqualizationFilterPath,'equalizationFilter');
                    app.pers.equalizationFilter = equalizationFilter;
                end

            elseif isempty(app.data.Settings.EqualizationFilterPath)

                app.pers.equalizationFilter = [];

            end

            % Apply equalization filter, if relevant
            if ~isempty(app.pers.equalizationFilter)

                % Get list of channels
                outputChannels = unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]);

                for cc = 1:size(signal,2)

                    % Identify which equalization filter to use
                    idxFilter = whichChannels(cc)==outputChannels;

                    % Apply
                    signal(:,cc) = filter(app.pers.equalizationFilter{idxFilter},1,signal(:,cc));

                end

            end

        end

        function playSignal(app,signalToPlay)

            % If in Basic mode, play using sound function and return
            if strcmp(app.data.Settings.PlaybackMethod,'Basic')

                sound(signalToPlay,app.data.Settings.Fs);
                pause(length(signalToPlay)/app.data.Settings.Fs);
                if app.data.Settings.PresentationMode == "Audio-visual"
                    warning('Audiovisual presentation is not possible using basic playback.');
                end
                return;

            end

            % Initialize visual part of target stimuli, if relevant
            if app.data.Settings.PresentationMode == "Audio-visual"
                initializeVisualPartOfStimuli(app);
            end

            % Start acoustic stimuli if using non-blocking playbackMethod
            if strcmp(app.data.Settings.PlaybackMethod,'Playrec')

                % Send signal via playrec
                playrec('play',signalToPlay,...
                    unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]));

                % Pause to align audio and video, if relevant
                if app.data.Settings.PresentationMode == "Audio-visual" && app.data.Settings.DurationBefore > 0.5
                    pause(app.data.Settings.DurationBefore-0.5);
                end

            elseif strcmp(app.data.Settings.PlaybackMethod,'SoundMexPro')

                % Send signal via SoundMexPro
                listOfChannels = unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]);
                fprintf('playSignal(): SMP playing %s', signalToPlay );% LABW
                soundmexpro('loadmem', ...
                    'data',signalToPlay, ...
                    'track',0:numel(listOfChannels)-1, ...
                    'loopcount',1 ...
                    );

                % Start player, if relevant
                if app.data.Settings.MaskerSettings == "Intermittent"
                    soundmexpro('start');
                end

                % Pause to align audio and video, if relevant
                if app.data.Settings.PresentationMode == "Audio-visual" && app.data.Settings.DurationBefore > 0.5
                    pause(app.data.Settings.DurationBefore-0.5);
                end

            end

            % Loop through the stimuli for block-based playback, if relevant
            if app.data.Settings.PresentationMode == "Audio-visual" || ...
                    strcmp(app.data.Settings.PlaybackMethod,'AudioToolbox')

                app.pers.playingFlag = true;
                app.iter.playingIdx = 1;
                while app.pers.playingFlag==true && app.iter.playingIdx<size(signalToPlay,1)

                    % Send the acoustic part of the buffer, if relevant
                    if strcmp(app.data.Settings.PlaybackMethod,'AudioToolbox')
                        playAcousticPartOfBuffer(app,signalToPlay);
                    end

                    % Send the visual part of the buffer, if relevant
                    if app.data.Settings.PresentationMode == "Audio-visual"
                        playVisualPartOfBuffer(app);
                    end

                    % Drawnow and update buffer index
                    drawnow;
                    app.iter.playingIdx = app.iter.playingIdx+app.data.Settings.PlaybackBuffer;

                end
            end

            % Conclude visual part of stimuli, if relevant
            if app.data.Settings.PresentationMode == "Audio-visual"
                concludeVisualPartOfStimuli(app);
            end

            % Wait until sentence is complete
            pause(length(app.data.Settings.PlaybackBuffer)/app.data.Settings.Fs);

            % Release audio objects
            releaseAudioObjs(app);

        end

    end

    % Functions that are only accessible by this app
    methods (Access = private)

        function loadCorpusInfo(app,whichToLoad)

            whichToLoad = char(whichToLoad);
            filename = fullfile(app.data.Settings.CorporaPath,['Corpus_' whichToLoad],...
                'metadata',['Corpus_' whichToLoad '.csv']);
            opts = detectImportOptions(filename);
            opts.Encoding = 'UTF-8';
            opts.VariableNamingRule = 'Preserve';
            app.pers.(['Corpus_' whichToLoad]) = readtable(filename,opts);

        end

        function loadListInfo(app,whichCorpus,whichLists)

            % Load list information
            whichCorpus = char(whichCorpus);
            whichLists = char(whichLists);
            filename = fullfile(app.data.Settings.CorporaPath,['Corpus_' whichCorpus],...
                'metadata',['List_' whichLists '.csv']);
            opts = detectImportOptions(filename);
            opts.Encoding = 'UTF-8';
            opts.VariableNamingRule = 'Preserve';
            opts = setvartype(opts,'List','string');
            app.pers.(['List_' whichLists]) = readtable(filename,opts);

        end

        function updateInfoForNextSent(app)

            % At the start (idx 0) in QuickStart with Random list option
            if app.pers.sentIdxInBlock == 0 &&...
                    app.AudioSettingsTabs.SelectedTab == app.QuickstartTab &&...
                    app.ListNumberDropDown.Value == "Random list"

                % Save which list will be used for all session
                randList = randi(length(app.ListNumberDropDown.Items)-1);
                app.pers.whichRandomList = num2str(randList);
            end

            % Reset iter struct
            app.iter = [];

            % Update counters
            app.pers.sentIdxInBlock = app.pers.sentIdxInBlock + 1; % current sentence
            app.pers.sentIdxInSession = app.pers.sentIdxInSession + 1; % for saving results

            % Read in info for the next sentence
            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab % Quick-start mode

                % Read quick start settings
                app.iter.corpusName = app.CorpusDropDown.Value;
                app.iter.target = app.TargetDropDown.Value;
                app.iter.masker = app.MaskerDropDown.Value;
                app.iter.listType = app.ListTypeDropDown.Value;
                if app.ListNumberDropDown.Value == "Random list"
                    app.iter.listNum = app.pers.whichRandomList;
                else
                    app.iter.listNum = app.ListNumberDropDown.Value;
                end

                % Load corpus information
                if ~isfield(app.pers,app.iter.corpusName)

                    loadCorpusInfo(app,app.iter.corpusName);

                end

                % Load list information
                if ~isfield(app.pers,app.iter.listType)

                    loadListInfo(app,app.iter.corpusName,app.iter.listType);

                end

                % Initialize counters
                idxSentencesInList = find(strcmp(app.iter.listNum,app.pers.(['List_' app.iter.listType]).List));
                app.iter.sentTotalInBlock = numel(idxSentencesInList);
                app.iter.sentNum = app.pers.(['List_' app.iter.listType]).Sentence(idxSentencesInList(app.pers.sentIdxInBlock));

                % Update levels
                if app.data.Settings.ResultType == "Speech reception threshold"
                    app.iter.maskerLevel = app.StartingMaskerLevelEditField.Value; % fix masker level to level specified in UI
                    if app.pers.sentIdxInBlock == 1
                        app.iter.targetLevel = app.StartingTargetLevelEditField.Value;
                    else
                        app.iter.targetLevel = app.iter.maskerLevel + app.pers.nextSignalToNoise;
                        app.pers.nextSignalToNoise = [];
                    end
                else % if "Percentage correct"
                    app.iter.targetLevel = app.StartingTargetLevelEditField.Value;
                    app.iter.maskerLevel = app.StartingMaskerLevelEditField.Value;
                end
                app.iter.signalToNoise = app.iter.targetLevel - app.iter.maskerLevel;

            elseif app.AudioSettingsTabs.SelectedTab == app.AdvancedTab % Advanced mode

                % Read from randomization file
                if any(ismember(app.pers.randomizationFile.Properties.VariableNames,'Sentence')) % sentence-based randomization file

                    % Grab sentence info
                    app.iter.sentTotalInBlock = nnz(app.pers.randomizationFile.Block==app.pers.blockIdxInSession);
                    app.iter.whichSentInBlock = app.pers.randomizationFile(app.pers.randomizationFile.Block==app.pers.blockIdxInSession,:);
                    app.iter.corpusName = char(app.iter.whichSentInBlock.Corpus(app.pers.sentIdxInBlock,:));
                    app.iter.sentNum = app.iter.whichSentInBlock.Sentence(app.pers.sentIdxInBlock,:);
                    if any(ismember(app.pers.randomizationFile.Properties.VariableNames,'Talker'))
                        app.iter.target = string(app.iter.whichSentInBlock.Talker(app.pers.sentIdxInBlock,:));
                    else
                        app.iter.target = string(app.iter.whichSentInBlock.Target(app.pers.sentIdxInBlock,:));
                    end
                    app.iter.masker = string(app.iter.whichSentInBlock.Masker(app.pers.sentIdxInBlock,:));

                    % Load corpus information
                    if ~isfield(app.pers,app.iter.corpusName)

                        loadCorpusInfo(app,app.iter.corpusName);

                    end

                    % Update levels
                    if app.data.Settings.ResultType == "Speech reception threshold"

                        % Fix masker level to specified in randomization file
                        if any("Masker level" == string(app.pers.randomizationFile.Properties.VariableNames))
                            app.iter.maskerLevel = app.iter.whichSentInBlock.("Masker level")(app.pers.sentIdxInBlock,:);
                        else
                            if app.SRTInQuietEditField.Enable == "on" && app.SRTInQuietEditField.Value > 45 && app.ModeDropDownInAdvanced.Value == "Unaided"
                                app.iter.maskerLevel = app.SRTInQuietEditField.Value + 20; % boost level
                            else
                                app.iter.maskerLevel = 65; % default masker level
                            end
                        end

                        % Specify target level
                        if app.pers.sentIdxInBlock == 1 && any("Target level" == string(app.pers.randomizationFile.Properties.VariableNames))
                            app.iter.targetLevel = app.iter.whichSentInBlock.("Target level")(app.pers.sentIdxInBlock,:);
                            app.iter.signalToNoise = app.iter.targetLevel - app.iter.maskerLevel;
                        elseif app.pers.sentIdxInBlock == 1
                            app.iter.signalToNoise = 0; % default starting SNR
                            app.iter.targetLevel = app.iter.maskerLevel;
                        else
                            app.iter.targetLevel = app.iter.maskerLevel + app.pers.nextSignalToNoise;
                            app.pers.nextSignalToNoise = [];
                        end

                    else % if "Percentage correct"

                        if any("Masker level" == string(app.pers.randomizationFile.Properties.VariableNames))
                            app.iter.maskerLevel = app.iter.whichSentInBlock.("Masker level")(app.pers.sentIdxInBlock,:);
                        else
                            if app.SRTInQuietEditField.Enable == "on" && app.SRTInQuietEditField.Value > 45 && app.ModeDropDownInAdvanced.Value == "Unaided"
                                app.iter.maskerLevel = app.SRTInQuietEditField.Value + 20; % boost level
                            else
                                app.iter.maskerLevel = 65; % default masker level
                            end
                        end

                        if any("Target level" == string(app.pers.randomizationFile.Properties.VariableNames))
                            app.iter.targetLevel = app.iter.whichSentInBlock.("Target level")(app.pers.sentIdxInBlock,:);
                        else
                            if app.SRTInQuietEditField.Enable == "on" && app.SRTInQuietEditField.Value > 45 && app.ModeDropDownInAdvanced.Value == "Unaided"
                                app.iter.targetLevel = app.SRTInQuietEditField.Value + 20; % boost level
                            else
                                app.iter.targetLevel = 65; % default target level (SNR 0)
                            end
                        end

                    end
                    app.iter.signalToNoise = app.iter.targetLevel - app.iter.maskerLevel;


                else % list-based randomization file

                    % filter according to TP nr LABW
                    rf = rowfilter(app.pers.randomizationFile);
                    tpnr = app.data.Settings.Participant;
                    if ~isnumeric(tpnr)
                        tpnr = str2double(app.data.Settings.Participant);
                    end
                    app.pers.randomizationFile = app.pers.randomizationFile (rf.TP == tpnr, :);
                    
                    % Grab corpus name, list info, etc.
                    app.iter.corpusName = char(app.pers.randomizationFile.Corpus(app.pers.blockIdxInSession,:));
                    app.iter.listType = char(app.pers.randomizationFile.('List type')(app.pers.blockIdxInSession,:));
                    app.iter.listNum = app.pers.randomizationFile.('List number')(app.pers.blockIdxInSession,:);
                    if any(ismember(app.pers.randomizationFile.Properties.VariableNames,'Talker'))
                        app.iter.target = string(app.pers.randomizationFile.Talker(app.pers.blockIdxInSession,:));
                    else
                        app.iter.target = string(app.pers.randomizationFile.Target(app.pers.blockIdxInSession,:));
                    end
                    app.iter.masker = string(app.pers.randomizationFile.Masker(app.pers.blockIdxInSession,:));

                    % LABW read preproc fields
                    app.data.Settings.Participant = app.pers.randomizationFile.('TP')(app.pers.blockIdxInSession,:);        % double
                    app.data.Settings.pSNR = app.pers.randomizationFile.('SNR')(app.pers.blockIdxInSession,:);              % double
                    app.data.Settings.Condition = app.pers.randomizationFile.('Condition')(app.pers.blockIdxInSession,:);   % double

                    % Load corpus information
                    if ~isfield(app.pers,app.iter.corpusName)

                        loadCorpusInfo(app,app.iter.corpusName);

                    end

                    % Load list information
                    if ~isfield(app.pers,app.iter.listType)

                        loadListInfo(app,app.iter.corpusName,app.iter.listType);

                    end

                    % Update levels
                    if app.data.Settings.ResultType == "Speech reception threshold"

                        % Fix masker level to specified in randomization file
                        if any("Masker level" == string(app.pers.randomizationFile.Properties.VariableNames))
                            app.iter.maskerLevel = app.pers.randomizationFile.("Masker level")(app.pers.blockIdxInSession,:);
                        else
                            if app.SRTInQuietEditField.Enable == "on" && app.SRTInQuietEditField.Value > 45 && app.ModeDropDownInAdvanced.Value == "Unaided"
                                app.iter.maskerLevel = app.SRTInQuietEditField.Value + 20; % boost level
                            else
                                app.iter.maskerLevel = 65; % default masker level
                            end
                        end

                        % Specify target level
                        if app.pers.sentIdxInBlock == 1 && any("Target level" == string(app.pers.randomizationFile.Properties.VariableNames))
                            app.iter.targetLevel = app.pers.randomizationFile.("Target level")(app.pers.blockIdxInSession,:);
                            app.iter.signalToNoise = app.iter.targetLevel - app.iter.maskerLevel;
                        elseif app.pers.sentIdxInBlock == 1
                            app.iter.signalToNoise = 0; % default starting SNR
                            app.iter.targetLevel = app.iter.maskerLevel;
                        else
                            app.iter.targetLevel = app.iter.maskerLevel + app.pers.nextSignalToNoise;
                            app.pers.nextSignalToNoise = [];
                        end

                    else % if "Percentage correct"

                        if any("Masker level" == string(app.pers.randomizationFile.Properties.VariableNames))
                            app.iter.maskerLevel = app.pers.randomizationFile.("Masker level")(app.pers.blockIdxInSession,:);
                        else
                            if app.SRTInQuietEditField.Enable == "on" && app.SRTInQuietEditField.Value > 45 && app.ModeDropDownInAdvanced.Value == "Unaided"
                                app.iter.maskerLevel = app.SRTInQuietEditField.Value + 20; % boost level
                            else
                                app.iter.maskerLevel = -40; % default masker level
                            end
                        end

                        if any("Target level" == string(app.pers.randomizationFile.Properties.VariableNames))
                            app.iter.targetLevel = app.pers.randomizationFile.("Target level")(app.pers.blockIdxInSession,:);
                        else
                            if app.SRTInQuietEditField.Enable == "on" && app.SRTInQuietEditField.Value > 45 && app.ModeDropDownInAdvanced.Value == "Unaided"
                                app.iter.targetLevel = app.SRTInQuietEditField.Value + 20; % boost level
                            else
                                app.iter.targetLevel = 65; % default target level (SNR 0)
                            end
                        end

                    end
                    app.iter.signalToNoise = app.iter.targetLevel - app.iter.maskerLevel;   % TODO LABW use to select file folder
                    idxSentencesInList = find(strcmp(app.iter.listNum,app.pers.(['List_' app.iter.listType]).List));
                    app.iter.sentTotalInBlock = numel(idxSentencesInList);
                    app.iter.sentNum = app.pers.(['List_' app.iter.listType]).Sentence(idxSentencesInList(app.pers.sentIdxInBlock));

                end

            end

            % Update text fields about the specific sentence being presented
            app.iter.sentText = app.pers.(['Corpus_' app.iter.corpusName]).Words(app.pers.(['Corpus_' app.iter.corpusName]).Sentence == app.iter.sentNum); % current text sentence
            app.CurrentSent.Text = sprintf('Sentence %04d (%i/%i)', app.iter.sentNum, app.pers.sentIdxInBlock, app.iter.sentTotalInBlock);
            if isfield(app.iter,'listNum') && ~isempty(app.iter.listNum)
                app.CurrentBlock.Text = sprintf('List %s (%i/%i)', app.iter.listNum, app.pers.blockIdxInSession, app.pers.blockTotal);
            else
                app.CurrentBlock.Text = sprintf('Block %i (%i/%i)', app.pers.blockIdxInSession, app.pers.blockIdxInSession, app.pers.blockTotal);
            end

            % Reset scoring buttons
            for button = 1:app.pers.totButtons
                app.(sprintf('W%iButton', button)).Visible = "off";
                app.(sprintf('W%iButton', button)).Value = 0;
                app.(sprintf('W%iButton', button)).FontWeight = 'normal';
                app.(sprintf('W%iButton', button)).Enable = 'on';
            end
            app.iter.KeywordsButtons = [];
            app.MarkallButton.Value = 0;

            % Update buttons with text and sizing
            buttonSz = 10; % initial button size (wo. words)
            letterSz = 11; % increment for each letter in word
            wordSpacing = 3; % spacing between buttons (words)
            lineSpacing = 40; % increased spacing between the two lines of buttons
            verticalOffset = 15; % move buttons upward in scoring panel
            str = string(app.iter.sentText);
            Words = strsplit(str);
            nWords = numel(Words);
            nLetters = strlength(str);

            % Check if Keywords are defined
            app.pers.keywordsAvailable = ismember('Keywords', app.pers.(['Corpus_' app.iter.corpusName]).Properties.VariableNames);

            % If keywords not defined, then default to word-by-word scoring
            if app.pers.keywordsAvailable == 0
                app.data.Settings.ScoringType = "Word-by-word";
            end

            % Update buttons with text and sizing
            if app.pers.keywordsAvailable == 1
                % Extract keywords for the current sentence
                app.iter.Keywords = app.pers.(['Corpus_' app.iter.corpusName]).Keywords(app.pers.(['Corpus_' app.iter.corpusName]).Sentence == app.iter.sentNum); % keywords

                % Divide keywords and remove possible spaces in between words
                app.iter.Keywords = strtrim(strsplit(string(app.iter.Keywords), ', '));

                % Extract positions of keywords
                keyword_positions = [];
                last_position = 0; % Initialize the last matched position
                for k = 1:numel(app.iter.Keywords)
                    for pos = last_position+1:numel(Words)
                        if strcmpi(Words{pos}, app.iter.Keywords{k})
                            keyword_positions = [keyword_positions, pos];
                            last_position = pos; % Update the last matched position
                            break; % Move to the next keyword
                        end
                    end
                end
            end
            centerGrid = app.ScoringPanel.Position(3)/2; % relative to scoring panel

            % Calculate total width for the first line of buttons
            firstLineWords = min(nWords, 9);
            totalSentWidth = sum(buttonSz + (letterSz * strlength(Words(1:firstLineWords)))) + (firstLineWords - 1) * wordSpacing;
            initPosition = centerGrid - totalSentWidth/2;
            totalSize = initPosition;

            for i = 1:nWords

                % Display each word onto button
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Text = Words(i);
                if app.pers.keywordsAvailable == 1
                    % Find keywords and make them bold based on position
                    if ismember(i, keyword_positions)
                        app.(sprintf('W%iButton', i)).FontWeight = 'bold';
                        app.iter.KeywordsButtons = [app.iter.KeywordsButtons i];
                    end
                end

                % Adjust button width
                app.(sprintf('W%iButton', i)).Position(3) = buttonSz + (letterSz * strlength(Words(i)));

                % Update button spacing
                if i <= 9
                    % First line of words
                    app.(sprintf('W%iButton', i)).Position(1) = totalSize;
                    app.(sprintf('W%iButton', i)).Position(2) = app.ScoringPanel.Position(4) / 2 + verticalOffset; % Center vertically and move upwardsdata.Settings
                    totalSize = totalSize + app.(sprintf('W%iButton', i)).Position(3) + wordSpacing;
                else
                    % Second line of words
                    if i == 10
                        % Recalculate initial position for the second line
                        totalSentWidth = sum(buttonSz + (letterSz * strlength(Words(10:end)))) + (nWords - 12) * wordSpacing;
                        initPosition = centerGrid - totalSentWidth/2;
                        totalSize = initPosition;
                    end
                    app.(sprintf('W%iButton', i)).Position(1) = totalSize;
                    app.(sprintf('W%iButton', i)).Position(2) = app.ScoringPanel.Position(4) / 2 - lineSpacing + verticalOffset; % Adjust vertical position for second line and move upwards
                    totalSize = totalSize + app.(sprintf('W%iButton', i)).Position(3) + wordSpacing;
                end

            end

            % Disable non-keyword buttons (when keyword-based scoring)
            if app.data.Settings.ScoringType == "Keyword-based"
                for m = 1:app.pers.totButtons
                    if ~any(app.iter.KeywordsButtons == m)
                        app.(sprintf('W%iButton', m)).Enable = "off";
                    end
                end
            end

            % Update paths to target and masker
            app.iter.targetFile = sprintf('%04d.wav', app.iter.sentNum);        % LABW TODO HACK
            app.iter.maskerFile = strcat(app.iter.masker, '.wav');
            app.iter.corpusDir = fullfile(app.data.Settings.CorporaPath,['Corpus_' app.iter.corpusName]);

            % LABW preproc files point to server
            %app.iter.targetDir = fullfile(app.iter.corpusDir,'speech',app.iter.target);
            ssnr = ['SNR_' num2str(app.data.Settings.pSNR)];
            app.iter.targetDir = fullfile(app.data.Settings.preproc.targetpath,['TP_' num2str(app.data.Settings.Participant)],ssnr ,app.iter.target, ['condition_' num2str(app.data.Settings.Condition)]);

            app.iter.maskerDir = fullfile(app.iter.corpusDir,'noise');
            app.iter.targetFullfile = fullfile(app.iter.targetDir, app.iter.targetFile);
            app.iter.maskerFullfile = fullfile(app.iter.maskerDir, app.iter.maskerFile);
            if app.data.Settings.PresentationMode == "Audio-visual"
                app.iter.videoFile = sprintf('%04d.mp4', app.iter.sentNum);
                app.iter.videoDir = fullfile(app.iter.corpusDir,'video',app.iter.target);
                app.iter.videoFullfile = fullfile(app.iter.videoDir, app.iter.videoFile);
            end

        end

        function loadMixAndPlaySentence(app)

            % Load the target
            loadTarget(app);

            % Update progress plots
            updateProgressPlotsWithUnscoredSentence(app);

            % Combine and play target and masker
            if app.data.Settings.MaskerSettings == "Intermittent"

                % Load masker
                loadMasker(app);

                % Mix
                [outputChannels,~,idxToChannels] = unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]);
                idxTarget = idxToChannels(1:length(app.data.Settings.PlaybackChannelsTarget));
                idxMasker = idxToChannels(length(app.data.Settings.PlaybackChannelsTarget)+1:end);
                signalToPlay = zeros(size(app.iter.targetSignal,1),numel(outputChannels));
                signalToPlay(:,idxTarget) = signalToPlay(:,idxTarget) + app.iter.targetSignal;
                signalToPlay(:,idxMasker) = signalToPlay(:,idxMasker) + app.iter.maskerSignal;

                % Play
                playSignal(app,signalToPlay);

                % Release audio objects
                releaseAudioObjs(app);

            elseif strcmp(app.data.Settings.PlaybackMethod,'Playrec') % Continuous with Playrec

                % Send signal via playrec
                playrec('play',app.iter.targetSignal,app.data.Settings.PlaybackChannelsTarget);

            elseif strcmp(app.data.Settings.PlaybackMethod,'SoundMexPro') % Continuous with SoundMexPro

                % Send signal via SoundMexPro
                listOfChannels = unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]);
                [~,idxFilter] = ismember(app.data.Settings.PlaybackChannelsTarget,listOfChannels);
                fprintf('loadMixAndPlaySentence(): SMP playing %s\n', app.iter.targetFullfile );  % LABW
                soundmexpro('loadmem', ...
                    'data', app.iter.targetSignal, ...
                    'track', idxFilter-1, ...
                    'loopcount', 1 ...
                    );

            else % Continuous with AudioToolbox

                % Indicate to playContinuousMaskerAndMixOnTheFly that the target is ready
                app.pers.playingFlag = true;
                app.iter.playingIdx = 1;

            end

            % Record if relevant
            if ~isempty(app.data.Settings.PlaybackChannelsMic)
                recordResponse(app);
            end

            % Re-enable continue button
            if app.PauseButton.Value == 1 || app.StopButton.Enable == "off" % if pause activated or end of test
                app.ContinueButton.Enable = "off";
            else
                app.ContinueButton.Enable = "on";
            end

        end

        function loadTarget(app)

            % Read the target file
            fprintf('loadTarget(): %s\n', app.iter.targetFullfile);
            [app.iter.targetSignal, fsfile] = audioread(app.iter.targetFullfile);  % LABW check fs in file

            % LABW resample if need be
            if fsfile ~= app.data.Settings.Fs
                % Compute the up and downsampling factors
                G = gcd(fsfile, app.data.Settings.Fs);
                n_up = app.data.Settings.Fs/G;
                n_down = fsfile/G;

                % Check if resampling is needed
                if n_up ~= n_down
                    %fprintf('loadTarget(): Resampling n_up %d, n_down %d\n', n_up, n_down);
                    app.iter.targetSignal = resample(app.iter.targetSignal, n_up, n_down);
                end

            end

            % Adjust padding if necessary
            if app.data.Settings.DurationBefore > 0.5

                app.iter.targetSignal = [zeros((app.data.Settings.DurationBefore-0.5)*app.data.Settings.Fs,1); ...
                    app.iter.targetSignal(:)];

            end

            % Replicate, if relevant
            app.iter.targetSignal = repmat(app.iter.targetSignal,1,...
                length(app.data.Settings.PlaybackChannelsTarget));

            % Apply equalization filter, if relevant
            app.iter.targetSignal = applyEqualizationFilter(app,...
                app.iter.targetSignal,app.data.Settings.PlaybackChannelsTarget);

            % Apply sentence-specific RMS adjustment, if relevent
            if app.data.Settings.AdjustSentenceLevelRMS == 1 && isfield(app.iter,'listNum')
                app.iter.targetLevelOffset = ...
                    app.pers.(['List_' app.iter.listType]).Offset(app.pers.(['List_' app.iter.listType]).Sentence == app.iter.sentNum);
            elseif app.data.Settings.AdjustSentenceLevelRMS == 1 && ismember('Inflection_F1A', app.pers.(['Corpus_' app.iter.corpusName]).Properties.VariableNames)
                app.iter.targetLevelOffset = ...
                    app.pers.(['Corpus_' app.iter.corpusName]).Inflection_F1A(app.pers.(['Corpus_' app.iter.corpusName]).Sentence == app.iter.sentNum) ...
                    - mean(app.pers.(['Corpus_' app.iter.corpusName]).Inflection_F1A);
            else
                app.iter.targetLevelOffset = 0;
            end

            % Scale to desired level
            app.iter.targetSignal = scaleToDesiredLevel(app,app.iter.targetSignal,...
                app.data.Settings.PlaybackChannelsTarget,...
                app.iter.targetLevel+app.iter.targetLevelOffset);

            % Apply hearing loss compensation, if relevent
            if app.data.Settings.ApplyHearingLossCompensation
                app.iter.targetSignal = applyHearingLossCompensation(app,app.iter.targetSignal);
            end

        end

        function loadMasker(app)

            % Read info about masker
            app.iter.maskerInfo = audioinfo(app.iter.maskerFullfile);

            % Specify desired length
            if app.data.Settings.MaskerSettings == "Continuous"
                desiredMaskerLength = 10*60*app.data.Settings.Fs; % NOTE Hard-coded to 10 min
            else
                desiredMaskerLength = length(app.iter.targetSignal);
            end

            % Read in random segment(s) of masker
            if app.data.Settings.PlaybackMode == "Headphone"
                numMaskers = 1; % assumes diotic presentation
            else % "Loudspeaker"
                numMaskers = length(app.data.Settings.PlaybackChannelsMasker); % assumes different random sample at each loudspeaker
            end
            maskerSignal = zeros(desiredMaskerLength,numMaskers);
            for mm = 1:numMaskers
                app.iter.maskerRandSample = round((app.iter.maskerInfo.TotalSamples-desiredMaskerLength)*rand); % TODO Save in data
                [maskerSignal(:,mm), fsfile] = audioread(app.iter.maskerFullfile,...
                    [app.iter.maskerRandSample, app.iter.maskerRandSample+desiredMaskerLength-1]);

                % LABW resample if need be
                if fsfile ~= app.data.Settings.Fs
                    % Compute the up and downsampling factors
                    G = gcd(fsfile, app.data.Settings.Fs);
                    n_up = app.data.Settings.Fs/G;
                    n_down = fsfile/G;

                    % Check if resampling is needed
                    if n_up ~= n_down
                        fprintf('loadMasker(): Resampling n_up %d, n_down %d\n', n_up, n_down);
                        maskerSignal(:,mm) = resample(maskerSignal(:,mm), n_up, n_down);
                    end

                end

            end

            % Add ramping to masker(s)
            for mm = 1:numMaskers
                maskerSignal(1:app.data.Settings.hannLen,mm) = ...
                    app.data.Settings.hannWindow(1:app.data.Settings.hannLen).*maskerSignal(1:app.data.Settings.hannLen,mm);
                maskerSignal(end-app.data.Settings.hannLen+1:end,mm) = ...
                    app.data.Settings.hannWindow(app.data.Settings.hannLen+1:end).*maskerSignal(end-app.data.Settings.hannLen+1:end,mm);
            end

            % Replicate, if relevant
            if app.data.Settings.PlaybackMode == "Headphone"
                maskerSignal = repmat(maskerSignal,1,...
                    length(app.data.Settings.PlaybackChannelsMasker));
            end

            % Apply equalization filter, if relevant
            maskerSignal = applyEqualizationFilter(app,maskerSignal,...
                app.data.Settings.PlaybackChannelsMasker);

            % Scale to desired level
            pressureSqTodB = @(p) 10*log10(p/(1e-12)); % ItodB = @(I) 10*log10(I/(1e-12));
            dBtoPressureSq = @(dB) 10^(dB/10)*1e-12; % dBtoI = @(dB) 10^(dB/10)*1e-12;
            maskerSignal = scaleToDesiredLevel(app,maskerSignal,...
                app.data.Settings.PlaybackChannelsMasker,...
                pressureSqTodB(dBtoPressureSq(app.iter.maskerLevel)/numMaskers)); % ItodB(dBtoI(app.iter.maskerLevel)/numMaskers));

            % Apply hearing loss compensation, if relevent
            if app.data.Settings.ApplyHearingLossCompensation
                maskerSignal = applyHearingLossCompensation(app,maskerSignal);
            end

            if app.data.Settings.MaskerSettings == "Continuous"
                app.pers.maskerSignal = maskerSignal;
            else
                app.iter.maskerSignal = maskerSignal;
            end

        end

        function signal = scaleToDesiredLevel(app,signal,whichChannels,level)

            % NOTE Assumes signal has a long-term RMS scaled to -26 dB FS
            % so that it matches the long-term RMS of the calibration signal
            % (i.e., -26 dB FS)

            % Scale the digital levels in each channel to get the desired physical level
            for cc = 1:size(signal,2)

                % Grab calibration level
                if isempty(app.data.Settings.CalibrationMeasuredLevel)
                    calibrationMeasuredLevel = 65; % default for HLCM: calibrated to 120 dB SPL = 0 dB FS
                else
                    listOfChannels = unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]);
                    idxFilter = find(whichChannels(cc) == listOfChannels);
                    if numel(app.data.Settings.CalibrationMeasuredLevel) < idxFilter
                        warning('There is a mismatch between your channel specification and level calibration. Please check your calibration.');
                        calibrationMeasuredLevel = 95; % default
                    else
                        calibrationMeasuredLevel = app.data.Settings.CalibrationMeasuredLevel(idxFilter);
                    end
                end

                % Calculate gain
                % TODO modify for DAST calibration
                gain = level-calibrationMeasuredLevel + app.data.Settings.UIGain;  % add TL control of gain (negative only)
                if cc == 1; fprintf('\tGain = %2.1f dB\n', gain); end
                signal(:,cc) = signal(:,cc)*(10^(gain/20));

            end

        end

        function signal = applyHearingLossCompensation(app,signal,whichCols)

            if nargin<3
                whichCols = 1:2; % assumes headphone-based and that the first two channels are being sent to the participant
            end

            if app.data.Settings.ApplyHearingLossCompensation

                % Load audiograms, if relevant
                if ~isempty(app.data.Settings.AudiogramFilePath) && ...
                        isempty(app.pers.audiograms)
                    if any(strfind(app.data.Settings.AudiogramFilePath,'.csv'))
                        opts = detectImportOptions(app.data.Settings.AudiogramFilePath);
                        opts.VariableNamingRule = 'Preserve';
                        opts.Encoding = 'UTF-8';
                        opts.VariableNamesLine = 1;
                        opts.DataLines = [2 Inf];
                        app.pers.audiograms = readtable(app.data.Settings.AudiogramFilePath,opts);
                    elseif any(strfind(app.data.Settings.AudiogramFilePath,'.xls'))
                        app.pers.audiograms = readtable(app.data.Settings.AudiogramFilePath,...
                            'ReadVariableNames',true,...
                            'VariableNamingRule','Preserve');
                    end

                end

                % Initialize compensation filter, if relevant
                if isempty(app.pers.hearingLossCompensation)

                    % Initialize
                    frequencies = str2double(app.pers.audiograms.Properties.VariableNames(4:end));
                    numTaps = 1024; % hard-coded
                    earSide = ["Left","Right"];
                    app.pers.hearingLossCompensation = [];

                    % Grab thresholds
                    for cc = 1:numel(earSide)

                        airConduction = table2array(app.pers.audiograms(...
                            strcmp(app.pers.audiograms.("Participant ID"),...
                            app.ParticipantIDEditField.Value) & ...
                            app.pers.audiograms.Conduction == "Air" & ...
                            app.pers.audiograms.Side == earSide(cc), ...
                            4:end));
                        boneConduction = table2array(app.pers.audiograms(...
                            strcmp(app.pers.audiograms.("Participant ID"),...
                            app.ParticipantIDEditField.Value) & ...
                            app.pers.audiograms.Conduction == "Bone" & ...
                            app.pers.audiograms.Side == earSide(cc), ...
                            4:end));

                        if ~isempty(airConduction) && any(airConduction>20)

                            % Calculate insertion gain and filter
                            app.pers.hearingLossCompensation{cc} = NALRP(app,...
                                frequencies,airConduction,boneConduction,numTaps);

                        else

                            app.pers.hearingLossCompensation{cc} = [];

                        end

                    end

                end

                % Apply hearing loss compensation, if relevant
                for cc = 1:numel(app.pers.hearingLossCompensation)
                    if ~isempty(app.pers.hearingLossCompensation{cc})
                        signal(:,whichCols(cc)) = filter(app.pers.hearingLossCompensation{cc},1,signal(:,whichCols(cc)));
                    end
                end

            end

        end

        function filterCoeffs = NALRP(app,freqAudiogram,airConduction,boneConduction,ntaps)

            % Linear NAL-R(P) prescription

            % Defaults
            freqNALRP = [ 250 500 750 1000 1500 2000 3000 4000 6000 ];
            thresholdCorrection = [ -17  -8  -3  1  1  -1  -2  -2 -2 ];
            profoundCorrection = ...
                [  0    0    0    0    0    0    0    0    0;
                4    3    1    0   -1   -2   -2   -2   -2;
                6    4    2    0   -2   -3   -3   -3   -3;
                8    5    2    0   -3   -5   -5   -5   -5;
                11    7    3    0   -3   -6   -6   -6   -6;
                13    8    4    0   -4   -8   -8   -8   -8;
                15    9    4    0   -5   -9   -9   -9   -9 ];
            if isempty(boneConduction)
                boneConduction = airConduction; % default to no air-bone gap
            end

            % Align the frequency vectors
            [~,idx] = intersect(freqNALRP,freqAudiogram,'stable');
            thresholdCorrection = thresholdCorrection(idx);
            profoundCorrection = profoundCorrection(:,idx);

            % NAL-R(P) formula
            threeFreqAverage = (airConduction(freqAudiogram == 500) + ...
                airConduction(freqAudiogram == 1000) + ...
                airConduction(freqAudiogram == 2000))/3;
            if threeFreqAverage <= 60 % NAL-R
                insertionGains = 0.15*threeFreqAverage + ...
                    0.31*airConduction + ...
                    0.25*(airConduction-boneConduction) + ...
                    thresholdCorrection;
            else % NAL-RP
                thresholdAt2k = airConduction(freqAudiogram == 2000);
                if thresholdAt2k < 95
                    profoundIndex = 1;
                elseif ( 95 >= thresholdAt2k ) && ( thresholdAt2k < 100 )
                    profoundIndex = 2;
                elseif ( 100 >= thresholdAt2k ) && ( thresholdAt2k < 105 )
                    profoundIndex = 3;
                elseif ( 105 >= thresholdAt2k ) && ( thresholdAt2k < 110 )
                    profoundIndex = 4;
                elseif ( 110 >= thresholdAt2k ) && ( thresholdAt2k < 115 )
                    profoundIndex = 5;
                elseif ( 115 >= thresholdAt2k ) && ( thresholdAt2k < 120 )
                    profoundIndex = 6;
                else
                    profoundIndex = 7;
                end
                insertionGains = 0.15*threeFreqAverage + ...
                    0.2*(threeFreqAverage-60) + ...
                    0.31*airConduction + ...
                    0.25*(airConduction-boneConduction) + ...
                    thresholdCorrection + ...
                    profoundCorrection(profoundIndex,:);
            end

            % Ensure positive gains
            insertionGains = max( 0, insertionGains );

            % Create filter
            freqOneThirdOctave = [100 125 160 200 250 315 400 500 630 800 ...
                1000 1250 1600 2000 2500 3150 4000 5000 6300 8000];
            insertionGainsOneThirdOctave = loginterp(app,...
                freqAudiogram,insertionGains,...
                freqOneThirdOctave);
            freq = [0 freqOneThirdOctave app.data.Settings.Fs/2]; % add 0 and fs/2
            freq = freq./(app.data.Settings.Fs/2); % normalize
            gain = [insertionGainsOneThirdOctave(1) ...
                insertionGainsOneThirdOctave ...
                insertionGainsOneThirdOctave(end)]; % add 0 and fs/2
            gain = 10.^((gain)/20); % dB to power
            filterCoeffs = fir2(ntaps,freq,gain);

        end

        function yi = loginterp(~,x,y,xi)

            yi = zeros(size(xi));
            for ni=1:length(xi)
                if xi(ni)<=x(1)
                    yi(ni)=y(1);
                elseif xi(ni)>=x(end)
                    yi(ni)=y(end);
                else
                    ns = find(x>xi(ni));
                    n = ns(1);
                    yi(ni) = y(n) + (y(n-1) - y(n))*log10(xi(ni)/x(n))/log10(x(n-1)/x(n));
                end
            end

        end

        function playContinuousMaskerAndMixOnTheFly(app)

            % Initialize
            [outputChannels,~,idxToChannels] = unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]);
            idxTarget = idxToChannels(1:length(app.data.Settings.PlaybackChannelsTarget));
            idxMasker = idxToChannels(length(app.data.Settings.PlaybackChannelsTarget)+1:end);

            % Pad masker, if necessary
            maskerNumFrames = ceil(size(app.pers.maskerSignal,1)/app.data.Settings.PlaybackBuffer);
            zeroPadLen = maskerNumFrames*app.data.Settings.PlaybackBuffer - size(app.pers.maskerSignal,1);
            app.pers.maskerSignal = [app.pers.maskerSignal;
                zeros(zeroPadLen,size(app.pers.maskerSignal,2))];

            % Loop through the masker
            app.pers.maskerPlayingFlag = true;
            if isempty(app.pers.maskerPlayingIdx)
                app.pers.maskerPlayingIdx = 1;
            end
            while app.pers.maskerPlayingFlag==true && ...
                    app.pers.maskerPlayingIdx < size(app.pers.maskerSignal,1)

                % Initialize buffer
                bufferToPlay = zeros(app.data.Settings.PlaybackBuffer,numel(outputChannels));

                % Buffer masker
                startMasker = app.pers.maskerPlayingIdx;
                endMasker = app.pers.maskerPlayingIdx+app.data.Settings.PlaybackBuffer-1;
                bufferToPlay(:,idxMasker) = app.pers.maskerSignal(startMasker:endMasker,:);

                % Mix target in on-the-fly, if relevant
                if app.pers.playingFlag

                    % Get the indices to the target
                    startTarget = app.iter.playingIdx;
                    endTarget = app.iter.playingIdx+app.data.Settings.PlaybackBuffer-1;

                    % Mix the target with the masker (pad if necessary)
                    lenTarget = size(app.iter.targetSignal,1);
                    numChan = size(app.iter.targetSignal,2);
                    bufferToPlay(:,idxTarget) = bufferToPlay(:,idxTarget) + ...
                        [app.iter.targetSignal(startTarget:min(endTarget,lenTarget),:);
                        zeros(max(0,endTarget-lenTarget),numChan)];

                    % Update flag or idx
                    if endTarget > lenTarget

                        % Turn off playing flag because sentence is finished
                        app.pers.playingFlag = false;

                    else

                        % Update index into target for the next loop
                        app.iter.playingIdx = app.iter.playingIdx+app.data.Settings.PlaybackBuffer;

                    end

                end

                % Send via playback object
                app.pers.playbackObjectPlayer(bufferToPlay);
                drawnow limitrate;

                % Update index into masker for the next loop
                app.pers.maskerPlayingIdx = app.pers.maskerPlayingIdx+app.data.Settings.PlaybackBuffer;

            end

            % Wait until buffer is complete
            pause(length(app.data.Settings.PlaybackBuffer)/app.data.Settings.Fs);

            % Release audio objects
            releaseAudioObjs(app);

        end

        function recordResponse(app)

            % Make subfolder for recordings, if relevant
            app.iter.resultRecordingsDir = fullfile(app.pers.resultDir,app.pers.resultName);
            if ~exist(app.iter.resultRecordingsDir, 'dir')
                mkdir(app.iter.resultRecordingsDir);
            end

            % Prepare for recording, if relevant
            app.pers.playbackObjectFileWriter = dsp.AudioFileWriter(...
                fullfile(app.iter.resultRecordingsDir,app.iter.targetFile),...
                'FileFormat','WAV',...
                'SampleRate',app.data.Settings.Fs);

            % Enable continue button after end of sentence
            app.ContinueButton.Enable = "on";

            % Record the response, if relevant
            drawnow; pause(0.05);
            tic;
            recordingMaxSec = 60;
            app.pers.recordingFlag = true;
            while app.pers.recordingFlag==true && toc < recordingMaxSec
                signalRecorded = app.pers.playbackObjectRecorder();
                app.pers.playbackObjectFileWriter(signalRecorded);
                drawnow; % update process for pending callbacks
            end

            % release audio objects
            releaseAudioObjs(app);

        end

        function setupPsychToolbox(app)

            sca;
            close all;
            PsychStartup;
            InitializePsychSound;
            Screen('Preference', 'SkipSyncTests', 2);
            PsychDefaultSetup(2);
            AssertOpenGL;

            % Get the screen numbers.
            app.iter.video.screens = Screen('Screens');
            %Screen('Preference','ConserveVRAM',64); % May be needed for weaker systems

            % Select screen (if external exists chooses external screen)
            screenNumber = max(app.iter.video.screens);

            % Open an on-screen window using PsychImaging and color it grey
            [app.iter.video.win, windowRect] = PsychImaging('OpenWindow', screenNumber, [189 190 186]/255);
            [screenXpixels, screenYpixels] = Screen('WindowSize', app.iter.video.win);
            [xCenter, yCenter] = RectCenter(windowRect);
            Screen('BlendFunction', app.iter.video.win, 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');

            % Read Grey Screen Image and display on screen
            imgloc = fullfile(pwd,'files','GreyBackground_Screening_filled.jpg');
            [app.pers.greyImage, ~, alpha] = imread(imgloc);
            [s1, s2, s3] = size(app.pers.greyImage);
            app.iter.video.imageTexture = Screen('MakeTexture', app.iter.video.win, app.pers.greyImage);
            Screen('Flip',app.iter.video.win,[],1);

        end

        function initializeVisualPartOfStimuli(app)

            % Prepare the video part of the stimuli
            app.iter.video.movie = Screen('OpenMovie', app.iter.video.win, app.iter.videoFullfile);
            WaitSecs(2);                                                   % TODO Can this be shorter (e.g., 0.1 s)?
            app.iter.video.ifi = Screen('GetFlipInterval', app.iter.video.win);
            topPriorityLevel = MaxPriority(app.iter.video.win); % retreive the maximum priority number
            Priority(topPriorityLevel); % set max priority
            WaitSecs(0.5);                                                 % TODO Can this be shorter (e.g., 0.1 s)?
            app.iter.video.vbl = Screen('Flip', app.iter.video.win);
            tic;
            [app.iter.video.droppedframes] = Screen('PlayMovie', app.iter.video.movie, 1);

        end

        function playAcousticPartOfBuffer(app,signalToPlay)

            % Buffer
            if (app.iter.playingIdx+app.data.Settings.PlaybackBuffer-1)<size(signalToPlay,1)
                signalToPlayBuffered = signalToPlay(app.iter.playingIdx:(app.iter.playingIdx+app.data.Settings.PlaybackBuffer-1),:);
            else
                zeroPadLen = app.data.Settings.PlaybackBuffer-size(signalToPlay(app.iter.playingIdx:end,:),1);
                signalToPlayBuffered = [signalToPlay(app.iter.playingIdx:end,:);
                    zeros(zeroPadLen,size(signalToPlay,2))];
            end

            % Send via playback object
            app.pers.playbackObjectPlayer(signalToPlayBuffered);

        end

        function playVisualPartOfBuffer(app)

            % Wait for next movie frame, retrieve texture handle to it
            tex = Screen('GetMovieImage', app.iter.video.win, app.iter.video.movie);

            % Break if movie is done (i.e., if texture is invalid)
            if tex <= 0
                return;
            end

            % Draw the new texture immediately to screen
            Screen('DrawTexture', app.iter.video.win, tex);

            % Update the display
            Screen('Flip', app.iter.video.win, 0);

            % Release the texture
            Screen('Close', tex);

        end

        function concludeVisualPartOfStimuli(app)

            % Stop playback
            Screen('PlayMovie', app.iter.video.movie, 0);

            % Close movie
            Screen('CloseMovie', app.iter.video.movie);
            Screen('DrawTexture', app.iter.video.win, app.iter.video.imageTexture);
            Screen('Flip', app.iter.video.win,[], 1);
            if app.pers.sentIdxInBlock == app.iter.sentTotalInBlock
                Screen('MakeTexture', app.iter.video.win, app.pers.greyImage);
                Screen('Flip',app.iter.video.win,[],1);
            end
            FlushEvents();

        end

        function saveSentenceLevelPartialResultsInFile(app)

            % Save word-by-word results, if relevant
            if app.data.Settings.ScoringType == "Word-by-word"
                wordsDisplay = strsplit(string(app.iter.sentText));
                numWordsSent = numel(wordsDisplay);
                app.iter.WordScore = zeros(1,numWordsSent);
                for i = 1:app.pers.totButtons
                    if app.(sprintf('W%iButton', i)).Value == 1
                        app.iter.WordScore(i) = 1; % Read out words correctly repeated
                    end
                end
                for w = 1:numWordsSent
                    app.data.WordScore(app.pers.sentIdxInSession, w) = ...
                        app.iter.WordScore(w);
                end
                for w = (numWordsSent+1):app.pers.totButtons
                    app.data.WordScore(app.pers.sentIdxInSession, w) = NaN;
                end
            end

            if app.pers.keywordsAvailable == 1
                % Save keyword-by-keyword results
                numKeywordsSent = numel(app.iter.Keywords);
                app.iter.Keywordscore = zeros(1,numKeywordsSent);
                for j = 1:length(app.iter.KeywordsButtons)
                    if app.(sprintf('W%iButton', app.iter.KeywordsButtons(j))).Value == 1
                        app.iter.Keywordscore(j) = 1; % Read out keywords correctly repeated
                    end
                end
                for w = 1:numKeywordsSent
                    app.data.KeywordScore(app.pers.sentIdxInSession, w) = ...
                        app.iter.Keywordscore(w);
                end
            end


            % Calculate score for current sentence
            if app.data.Settings.ScoringType == "Word-by-word"
                numCorrectWords = sum(app.iter.WordScore,'omitnan');
                app.iter.SentenceScore = (numCorrectWords/numWordsSent)*100;
            else
                numCorrectWords = sum(app.iter.Keywordscore);
                app.iter.SentenceScore = (numCorrectWords/numKeywordsSent)*100;
            end

            % Write this and other important info to data struct
            if ~isfield(app.data,'SentenceInfo') % initialize if it doesn't exist
                if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab ||...
                        (app.AudioSettingsTabs.SelectedTab == app.AdvancedTab &&...
                        ~any(ismember(app.pers.randomizationFile.Properties.VariableNames,'Sentence')))
                    % Add List number if QuickStart or advanced with list definition
                    varTypes = ["double", "string", "string", "double", "cell", "cell", "double", "double", "double", "double", "double", "double", "double", "datetime"];
                    varNames = ["Block", "List type", "List", "Sentence", "Target", "Masker", "Target level", "Masker level", "SNR", "Condition"...
                        "Sentence score", "Score estimate", "Score variability", "Timestamp"];  % LABW
                else % sentence-based csv randomization file
                    varTypes = ["double", "double", "cell", "cell", "double", "double", "double", "double", "double", "datetime"];
                    varNames = ["Block", "Sentence", "Target", "Masker", "Target level", "Masker level",...
                        "Sentence score", "Score estimate", "Score variability", "Timestamp"];
                end
                if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab % Quick start
                    sz = [app.iter.sentTotalInBlock, length(varNames)];
                elseif (app.AudioSettingsTabs.SelectedTab == app.AdvancedTab && ... % list-based csv randomization file
                        ~any(ismember(app.pers.randomizationFile.Properties.VariableNames,'Sentence')))
                    sz = [app.iter.sentTotalInBlock * app.pers.blockTotal, length(varNames)]; % hack (potentially problematically if sentTotalInBlock changes size with block)
                else % sentence-based csv randomization file
                    sz = [height(app.pers.randomizationFile), length(varNames)];
                end
                app.data.SentenceInfo = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);

            end

            % Save sentence information
            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab ||...
                    (app.AudioSettingsTabs.SelectedTab == app.AdvancedTab &&...
                    ~any(ismember(app.pers.randomizationFile.Properties.VariableNames,'Sentence')))

                app.data.SentenceInfo.("List type")(app.pers.sentIdxInSession) = app.iter.listType;
                app.data.SentenceInfo.List(app.pers.sentIdxInSession) = app.iter.listNum;

            end
            app.data.SentenceInfo.Block(app.pers.sentIdxInSession) = app.pers.blockIdxInSession;
            app.data.SentenceInfo.Sentence(app.pers.sentIdxInSession) = app.iter.sentNum;
            app.data.SentenceInfo.Target(app.pers.sentIdxInSession) = cellstr(app.iter.target);
            app.data.SentenceInfo.Masker(app.pers.sentIdxInSession) = cellstr(app.iter.masker);
            app.data.SentenceInfo.("Target level")(app.pers.sentIdxInSession) = app.iter.targetLevel;
            app.data.SentenceInfo.("Masker level")(app.pers.sentIdxInSession) = app.iter.maskerLevel;
            
            app.data.SentenceInfo.("SNR")(app.pers.sentIdxInSession) = app.data.Settings.pSNR; % LABW
            app.data.SentenceInfo.("Condition")(app.pers.sentIdxInSession) = app.data.Settings.Condition; % LABW

            app.data.SentenceInfo.("Sentence score")(app.pers.sentIdxInSession) = app.iter.SentenceScore;
            app.data.SentenceInfo.Timestamp(app.pers.sentIdxInSession) = datetime('now');

            % Save mat file
            data = app.data; %#ok<ADPROP>
            save(app.pers.resultFullfile, 'data', '-mat');

        end

        function saveBlockLevelPartialResultsInFile(app)

            % Save block scores
            app.data.SentenceInfo.("Score estimate")(app.pers.sentIdxInSession) = app.iter.BlockScore;
            app.data.SentenceInfo.("Score variability")(app.pers.sentIdxInSession) = app.iter.BlockVariability;

            % Write this and other important info to data struct
            if ~isfield(app.data,'BlockResults') % initialize if it doesn't exist

                if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab

                    varTypes = ["double", "string", "string", "double", "double", "string", "double"];
                    varNames = ["Block", "List type", "List", "Score", "Variability", "Mode", "UIGain"];

                elseif (app.AudioSettingsTabs.SelectedTab == app.AdvancedTab &&...
                        ~any(ismember(app.pers.randomizationFile.Properties.VariableNames,'Sentence'))) % AdvancedTab, list-based

                    varTypes = ["double", "string", "string", "double", "double", "double", "double", "string", "double", "double"];
                    varNames = ["Block", "List type", "List", "SNR", "Condition", "Score", "Variability", "Mode", "SRT in quiet", "UIGain"];  % LABW edit

                else % AdvancedTab, sentence-based

                    varTypes = ["double", "double", "double", "string", "double", "double"];
                    varNames = ["Block", "Score", "Variability", "Mode", "SRT in quiet", "UIGain"];

                end
                sz = [app.pers.blockTotal, length(varNames)];
                app.data.BlockResults = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);

            end

            % Store block-level scores
            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab ||...
                    (app.AudioSettingsTabs.SelectedTab == app.AdvancedTab &&...
                    ~any(ismember(app.pers.randomizationFile.Properties.VariableNames,'Sentence')))

                app.data.BlockResults.("List type")(app.pers.blockIdxInSession) = app.iter.listType;
                app.data.BlockResults.List(app.pers.blockIdxInSession) = app.iter.listNum;

            end
            app.data.BlockResults.Block(app.pers.blockIdxInSession) = app.pers.blockIdxInSession;
            app.data.BlockResults.Score(app.pers.blockIdxInSession) = app.iter.BlockScore;
            app.data.BlockResults.Variability(app.pers.blockIdxInSession) = app.iter.BlockVariability;
            app.data.BlockResults.UIGain(app.pers.blockIdxInSession) = app.data.Settings.UIGain;% LABW
            app.data.BlockResults.SNR(app.pers.blockIdxInSession) = app.data.Settings.pSNR;
            app.data.BlockResults.Condition(app.pers.blockIdxInSession) = app.data.Settings.Condition;

          
            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab

                app.data.BlockResults.Mode(app.pers.blockIdxInSession) = app.ModeDropDownInQuickStartTab.Value;

            elseif (app.AudioSettingsTabs.SelectedTab == app.AdvancedTab &&...
                    ~any(ismember(app.pers.randomizationFile.Properties.VariableNames,'Sentence'))) % AdvancedTab, list-based

                app.data.BlockResults.Mode(app.pers.blockIdxInSession) = app.ModeDropDownInAdvanced.Value;
                app.data.BlockResults.("SRT in quiet")(app.pers.blockIdxInSession) = app.SRTInQuietEditField.Value;

            else % AdvancedTab, sentence-based

                app.data.BlockResults.Mode(app.pers.blockIdxInSession) = app.ModeDropDownInAdvanced.Value;
                app.data.BlockResults.("SRT in quiet")(app.pers.blockIdxInSession) = app.SRTInQuietEditField.Value;

            end

            % Save mat file
            data = app.data; %#ok<ADPROP>
            save(app.pers.resultFullfile, 'data', '-mat');

        end

        function measurementComplete = adjustSNRStandardMethod(app) % Inspired by Keidser et al. 2013 (International Journal of Audiology 2013; 52: 795–800)

            % Initialize helper functions
            findFirstReversal = @(x) find(diff(x)>=0,1);
            getIdxs = @(N) (app.pers.sentIdxInSession-N+1):app.pers.sentIdxInSession;
            getSNRs = @(N) app.data.SentenceInfo.("Target level")(getIdxs(N)) - ...
                app.data.SentenceInfo.("Masker level")(getIdxs(N));
            calculateSE = @(listOfSNRs) 2*std( listOfSNRs(find(app.pers.adaptiveTrackPhase>=2,1):end) )/...
                sqrt( numel(listOfSNRs)-find(app.pers.adaptiveTrackPhase<2,1,'last') );
            calculateSRT = @(listOfSNRs) mean(listOfSNRs(find(app.pers.adaptiveTrackPhase>=2,1):end));

            % Initialize flag
            measurementComplete = false;

            % Grab list of presented SNRs
            listOfSNRs = getSNRs(app.pers.sentIdxInBlock);

            % Compute next SNR according to phase
            if ... % >>> Phase 0 <<<
                    (app.pers.sentIdxInBlock == 1) && ...
                    (app.iter.SentenceScore < 100)


                app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock) = 0;
                app.iter.BlockVariability = NaN;
                app.iter.BlockScore = NaN;
                app.iter.targetLevel = app.iter.targetLevel + 5; % update current level for re-playing
                app.iter.signalToNoise = app.iter.targetLevel - app.iter.maskerLevel; % recalculate the SNR
                app.pers.nextSignalToNoise = app.iter.signalToNoise;

            elseif ... % >>> Phase 1 <<<
                    (app.pers.sentIdxInBlock < 4) || ...
                    isempty(findFirstReversal(listOfSNRs))

                app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock) = 1;
                app.iter.BlockVariability = NaN;
                app.iter.BlockScore = NaN;
                if app.iter.SentenceScore > app.data.Settings.PsychFuncPoint
                    app.pers.nextSignalToNoise = app.iter.signalToNoise - 5;
                elseif round(app.iter.SentenceScore) == app.data.Settings.PsychFuncPoint
                    app.pers.nextSignalToNoise = app.iter.signalToNoise;
                else
                    app.pers.nextSignalToNoise = app.iter.signalToNoise + 5;
                end

            elseif ...  % >>> Phase 2 <<<
                    ( app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock-1) == 1 || ...
                    app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock-1) == 2 )

                app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock) = 2;
                app.iter.BlockVariability = calculateSE(listOfSNRs);
                app.iter.BlockScore = calculateSRT(listOfSNRs);

                if  (app.pers.sentIdxInBlock < find(app.pers.adaptiveTrackPhase==2,1)+4) || ...
                        app.iter.BlockVariability > 1

                    if app.iter.SentenceScore > app.data.Settings.PsychFuncPoint
                        app.pers.nextSignalToNoise = app.iter.signalToNoise - 2;
                    elseif round(app.iter.SentenceScore) == app.data.Settings.PsychFuncPoint
                        app.pers.nextSignalToNoise = app.iter.signalToNoise;
                    else
                        app.pers.nextSignalToNoise = app.iter.signalToNoise + 2;
                    end

                else % >>> Jump to Phase 3 <<<

                    app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock) = 3;
                    if app.iter.SentenceScore > app.data.Settings.PsychFuncPoint
                        app.pers.nextSignalToNoise = app.iter.signalToNoise - 1;
                    elseif round(app.iter.SentenceScore) == app.data.Settings.PsychFuncPoint
                        app.pers.nextSignalToNoise = app.iter.signalToNoise;
                    else
                        app.pers.nextSignalToNoise = app.iter.signalToNoise + 1;
                    end

                end

            elseif ... % >>> Phase 3 <<<
                    ( (app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock-1) == 2) || ...
                    app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock-1) == 3 )

                app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock) = 3;
                app.iter.BlockVariability = calculateSE(listOfSNRs);
                app.iter.BlockScore = calculateSRT(listOfSNRs);

                if  (app.pers.sentIdxInBlock < find(app.pers.adaptiveTrackPhase==2,1)+8) || ... % Keidser suggests a default of 16 sentences in Phase 2 here but applying 8 instead
                        app.iter.BlockVariability > app.data.Settings.StoppingCriteria

                    if app.iter.SentenceScore > app.data.Settings.PsychFuncPoint
                        app.pers.nextSignalToNoise = app.iter.signalToNoise - 1;
                    elseif round(app.iter.SentenceScore) == app.data.Settings.PsychFuncPoint
                        app.pers.nextSignalToNoise = app.iter.signalToNoise;
                    else
                        app.pers.nextSignalToNoise = app.iter.signalToNoise + 1;
                    end

                else % >>> Stopping criteria met so stop early <<<

                    app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock) = 4;
                    measurementComplete = true;

                end

            else % >>> SRT measurement complete <<<

                app.pers.adaptiveTrackPhase(app.pers.sentIdxInBlock) = 4;
                app.iter.BlockVariability = calculateSE(listOfSNRs);
                app.iter.BlockScore = calculateSRT(listOfSNRs);
                measurementComplete = true;

            end

        end

        function measurementComplete = adjustSNRExperimentalMethod(app)

            % Fill in as desired

        end

        function updateProgressPlots(app)

            % Initialize helper functions
            getIdxs = @(N) (app.pers.sentIdxInSession-N+1):app.pers.sentIdxInSession;
            getSNRs = @(N) app.data.SentenceInfo.("Target level")(getIdxs(N)) - ...
                app.data.SentenceInfo.("Masker level")(getIdxs(N));
            listOfSNRs = getSNRs(app.pers.sentIdxInBlock);

            % >>> SentIdxVsSNR <<<
            % Plot SentIdxVsSNR
            app.SentIdxVsSNR.Visible = "on";
            hold(app.SentIdxVsSNR,'off');
            plot(app.SentIdxVsSNR,...
                1:app.pers.sentIdxInBlock,... % x-values
                listOfSNRs,... % y-values
                '-o',...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','k',...
                'Color','k',...
                'LineWidth',2.5);
            hold(app.SentIdxVsSNR,'on');

            % Update progress plot's x-axis
            set(app.SentIdxVsSNR,'XLim',[0 app.iter.sentTotalInBlock + 1]);
            app.SentIdxVsSNR.XTick = (1:app.iter.sentTotalInBlock);

            % Add vertical line to SentIdxVsSNR once in Phase 2
            if app.data.Settings.ResultType == "Speech reception threshold" && ...
                    app.pers.adaptiveTrackPhase(end)>=2 && ...
                    (app.pers.sentIdxInBlock >= find(app.pers.adaptiveTrackPhase>=2,1))
                plot(app.SentIdxVsSNR,...
                    find(app.pers.adaptiveTrackPhase>=2,1)*ones(2,1),... % x-values
                    [-40 40],... % y-values
                    ':k',...
                    'LineWidth',1.0);
            end

            % Add SRT estimate and error to SentIdxVsSNR
            if app.data.Settings.ResultType == "Speech reception threshold" && ...
                    ~isnan(app.iter.BlockScore)
                plot(app.SentIdxVsSNR,...
                    [0 app.iter.sentTotalInBlock + 1],... % x-values
                    app.iter.BlockScore*ones(1,2),... % y-value
                    '-k',...
                    'LineWidth',2.0);
                plot(app.SentIdxVsSNR,...
                    [0 app.iter.sentTotalInBlock + 1],... % x-values
                    (app.iter.BlockScore+app.iter.BlockVariability)*ones(1,2),... % y-value
                    '-.k',...
                    'LineWidth',1.5);
                plot(app.SentIdxVsSNR,...
                    [0 app.iter.sentTotalInBlock + 1],... % x-values
                    (app.iter.BlockScore-app.iter.BlockVariability)*ones(1,2),... % y-value
                    '-.k',...
                    'LineWidth',1.5);
            end

            % Add second vertical line to SentIdxVsSNR once in Phase 3
            if app.data.Settings.ResultType == "Speech reception threshold" && ...
                    app.pers.adaptiveTrackPhase(end)>=3 && ...
                    (app.pers.sentIdxInBlock >= find(app.pers.adaptiveTrackPhase>=3,1))
                plot(app.SentIdxVsSNR,...
                    find(app.pers.adaptiveTrackPhase>=3,1)*ones(2,1),... % x-values
                    [-40 40],... % y-values
                    ':k',...
                    'LineWidth',1.0);
            end

            % >>> SNRVsPercent <<<
            % Add new SNR to SNRVsPercent
            app.SNRVsPercent.Visible = "on";
            hold(app.SNRVsPercent,'off');
            scatter(app.SNRVsPercent,...
                listOfSNRs,... % x-values
                app.data.SentenceInfo.("Sentence score")(getIdxs(app.pers.sentIdxInBlock)),... % y-values
                'filled',...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','k');
            hold(app.SNRVsPercent,'on');
            if (app.data.Settings.ResultType == "Percent correct") && numel(unique(listOfSNRs)) == 1
                alpha(app.SNRVsPercent,0.1);
            end
            set(app.SNRVsPercent,'XLim',...
                [min([min([listOfSNRs;app.pers.nextSignalToNoise])-2 -15])...
                max([max([listOfSNRs;app.pers.nextSignalToNoise])+2 +5])]);
            set(app.SNRVsPercent,'YLim',[-10 110]);

            % Add psychometric function estimate to SNRVsPercent
            if ~isnan(app.iter.BlockScore)
                if app.data.Settings.ResultType == "Speech reception threshold"
                    plot(app.SNRVsPercent,...
                        app.iter.BlockScore*ones(1,2),... % x-values
                        [-10 110],... % y-values
                        '-k',...
                        'LineWidth',2.0);
                    plot(app.SNRVsPercent,...
                        (app.iter.BlockScore+app.iter.BlockVariability)*ones(1,2),... % x-values
                        [-10 110],... % y-values
                        '-.k',...
                        'LineWidth',1.5);
                    plot(app.SNRVsPercent,...
                        (app.iter.BlockScore-app.iter.BlockVariability)*ones(1,2),... % x-values
                        [-10 110],... % y-values
                        '-.k',...
                        'LineWidth',1.5);
                else
                    plot(app.SNRVsPercent,...
                        get(app.SNRVsPercent,'Xlim'),... % x-values
                        app.iter.BlockScore*ones(1,2),... % y-values
                        '-k',...
                        'LineWidth',2.0);
                    plot(app.SNRVsPercent,...
                        get(app.SNRVsPercent,'Xlim'),... % x-values
                        (app.iter.BlockScore+app.iter.BlockVariability)*ones(1,2),... % y-values
                        '-.k',...
                        'LineWidth',1.5);
                    plot(app.SNRVsPercent,...
                        get(app.SNRVsPercent,'Xlim'),... % x-values
                        (app.iter.BlockScore-app.iter.BlockVariability)*ones(1,2),... % y-values
                        '-.k',...
                        'LineWidth',1.5);
                end
                if app.data.Settings.ResultType == "Speech reception threshold" || ...
                        (app.data.Settings.ResultType == "Percent correct" && numel(unique(listOfSNRs)) > 1)

                    % Fit
                    x = listOfSNRs;
                    y = app.data.SentenceInfo.("Sentence score")(getIdxs(app.pers.sentIdxInBlock));
                    fitFunc = @(c,vals) 100./( 1 + exp(-4*(c(2)/100)*(vals-c(1))) );
                    leastSquaresCostFunc = @(c) sum((fitFunc(c,x) - y).^2);
                    fitCoeffs = fminsearch(leastSquaresCostFunc,[app.iter.BlockScore 20]);

                    % Plot
                    xvals = linspace(min(xlim(app.SNRVsPercent)),max(xlim(app.SNRVsPercent)),100);
                    plot(app.SNRVsPercent,...
                        xvals,...
                        fitFunc(fitCoeffs,xvals),...
                        'Color','k',...
                        'LineWidth',2.0);
                end

                % Add scores and variability to Progress bar
                ylimits = get(app.SentIdxVsSNR,'ylim');
                if app.data.Settings.ResultType == "Speech reception threshold"
                    % app.ProgressPanel.Title = sprintf('Progress: Current score %1.1f dB (SE = %1.1f)',app.iter.BlockScore,app.iter.BlockVariability);
                    text(app.SentIdxVsSNR,app.iter.sentTotalInBlock,ylimits(1)+0.10*diff(ylimits),...
                        sprintf('Score: %1.1f dB (SE = %1.1f)',app.iter.BlockScore,app.iter.BlockVariability),...
                        'HorizontalAlignment','right','FontSize',10);
                else
                    % app.ProgressPanel.Title = sprintf('Progress: Current score %1.1f%%',app.iter.BlockScore);
                    text(app.SentIdxVsSNR,app.iter.sentTotalInBlock,ylimits(1)+0.10*diff(ylimits),...
                        sprintf('Score: %1.1f%%',app.iter.BlockScore),...
                        'HorizontalAlignment','right','FontSize',10);
                end

            end

        end

        function updateProgressPlotsWithUnscoredSentence(app)

            % >>> SentIdxVsSNR <<<
            % Plot SentIdxVsSNR
            app.SentIdxVsSNR.Visible = "on";
            set(app.SentIdxVsSNR,'XLim',[0 app.iter.sentTotalInBlock + 1]);
            app.SentIdxVsSNR.XTick = (1:app.iter.sentTotalInBlock);
            if all(ylim(app.SentIdxVsSNR) == [0 1])
                set(app.SentIdxVsSNR,'YLim',[-15 15]);
            end
            plot(app.SentIdxVsSNR,...
                app.pers.sentIdxInBlock,... % x-values
                app.iter.signalToNoise,... % y-values
                'o',...
                'Color',0.7*ones(1,3),...
                'LineWidth',2.5,...
                'LineStyle','none');

        end

        function saveFinalResultsInFile(app)

            if app.BasicInformationPanel.Enable == "on"

                % if user pressed Stop and going back to test setup
                app.pers.sentIdxInSession = app.pers.sentIdxInSession - 1;

            end

            if app.pers.sentIdxInSession <= 0

                % do nothing (start of test)

            elseif app.pers.sentIdxInBlock == app.iter.sentTotalInBlock || app.pers.blockIdxInSession == app.pers.blockTotal % end of the block/list/test

                % Isolate data struct
                data = app.data; %#ok<ADPROP>

                % Save
                save(app.pers.resultFullfile, 'data', '-mat');

            end

        end

        function checkForMissingValuesInQuickStartMode(app)

            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab

                if isempty(app.CorpusDropDown.Value) || ...
                        isempty(app.TargetDropDown.Value) || ...
                        isempty(app.MaskerDropDown.Value) || ...
                        isempty(app.ListTypeDropDown.Value) || ...
                        isempty(app.ListNumberDropDown.Value)

                    app.StartButton.Enable = "off";

                else

                    app.StartButton.Enable = "on";

                end

            end

        end

        function disableButtons_initialState(app)

            % User input enabled
            app.StartButton.Enable = "on";
            app.BasicInformationPanel.Enable = "on";
            app.Panel.Enable = "on";
            app.UploadedPresetEditField.Enable = "on";
            app.LoadPresetButton.Enable = "on";
            app.SettingsButton.Enable = "on";
            app.GaindBSpinner.Enable = "on";

            % Test tab disabled
            app.PauseButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.NextButton.Enable = "off";
            app.MarkallButton.Enable = "off";

            app.iter.KeywordsButtons =[];
            app.MarkallButton.Value = 0;
            app.PauseButton.Value = 0;

            for i = 1:app.pers.totButtons
                app.(sprintf('W%iButton', i)).Visible = "off";
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
            end

            app.MarkallButton.Visible = "off";
            app.NextButton.Visible = "off";
            app.CurrentBlock.Visible = "off";
            app.CurrentSent.Visible = "off";
            app.ContinueButton.Visible = "off";
            app.PlayButton.Visible = "off";

            % Deactivate flag for scoring (to disable keyboard shortcuts)
            app.pers.scoringAvailableFlag = false;

        end

        function enableButtons_testState(app)

            % User input disabled
            app.StartButton.Enable = "off";
            app.BasicInformationPanel.Enable = "off";
            app.Panel.Enable = "off";
            app.UploadedPresetEditField.Enable = "off";
            app.LoadPresetButton.Enable = "off";
            app.SettingsButton.Enable = "off";
            app.GaindBSpinner.Enable = "off";

            % Test tab enabled
            app.PauseButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.MarkallButton.Enable = "on";
            app.ContinueButton.Enable = "off";
            for i = 1:app.pers.totButtons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Enable = "on";
            end
            app.MarkallButton.Visible = "on";
            app.ContinueButton.Visible = "on";
            app.Instructions.Visible = "on";
            app.NextButton.Visible = "off";
            app.CurrentBlock.Visible = "on";
            app.CurrentSent.Visible = "on";
            app.Instructions.Text = 'Mark correctly repeated words';

            % Initialize flag for scoring (to enable keyboard shortcuts)
            app.pers.scoringAvailableFlag = true;

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Force gray background in case user changed default
            app.UIFigure.Color = [0.94,0.94,0.94];

            % Intialize variables
            app.data = []; % everything that gets saved in result file (i.e., DATA)
            app.pers = []; % everything that doesnt get saved but persists throughout the session (i.e., PERSISTENT)
            app.iter = []; % everything that changes from sentence to sentence (i.e., ITERATION)

            % Shuffle randomization
            rng('shuffle');

            % Check and set current directory
            if isdeployed
                % User is running an executable in standalone mode.
                [~ , result] = system('set PATH'); %  [~, result] = system('path');
                relativeFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                cd (relativeFolder)
            end

            % Intializing variables
            app.pers.date = datestr(now,'yyyy_mm_dd');
            app.FilenameEditField.Value = sprintf(app.pers.date);

            % Make subfolder for presets, if relevant
            app.pers.presetDir = fullfile(pwd, 'presets');
            if ~exist(app.pers.presetDir, 'dir')
                mkdir(app.pers.presetDir);
            end

            % Make subfolder for results, if relevant
            app.pers.resultDir = fullfile(pwd, 'results');
            if ~exist(app.pers.resultDir, 'dir')
                mkdir(app.pers.resultDir);
            end

            % Load the default settings
            load(fullfile(app.pers.presetDir,'DAST_default_settings.mat'),'Settings');
            app.data.Settings = Settings;

            % LABW add preproc defaults
            app.data.Settings.preproc = preproc_defaults;

            % Update default audio and video directories, if necessary
            if ~exist(app.data.Settings.CorporaPath,'dir')
                app.data.Settings.CorporaPath = pwd;
            end

            % Update the interface
            app.UploadedPresetEditField.Value = 'DAST_default_settings';
            app.pers.UploadedPreset = app.UploadedPresetEditField.Value;


            % Populate dropdowns
            CorpusDropDownOpening(app);
            TargetDropDownOpening(app);
            MaskerDropDownOpening(app);
            ListTypeDropDownOpening(app);
            ListTypeDropDownValueChanged(app);

            % Keywords and Buttons
            app.iter.KeywordsButtons = [];
            app.pers.totButtons = 15;

            % Disable buttons
            disableButtons_initialState(app)
            checkForMissingValuesInQuickStartMode(app)
            app.pers.sentIdxInSession = -1;
            app.StartingBlockDropDown.Visible = "off";
            app.StartingblockDropDownLabel.Visible = "off";

            % Erase data in progress plots and make invisible
            cla(app.SentIdxVsSNR);
            cla(app.SNRVsPercent);
            app.ProgressPanel.Title = 'Progress';
            app.SentIdxVsSNR.Visible = "off";
            app.SNRVsPercent.Visible = "off";

            % Playback setup
            app.pers.playbackObjectPlayer = [];
            app.pers.playbackObjectRecorder = [];
            app.pers.playbackObjectFileWriter = [];
            app.pers.recordingFlag = false;
            app.pers.playingFlag = false;

        end

        % Value changed function: FilenameEditField, 
        % ...and 2 other components
        function TestInfoChanged(app, event)
            if isempty(app.TesterIDEditField.Value); Leader = '';
            else; Leader = strcat('_', app.TesterIDEditField.Value); end
            if isempty(app.ParticipantIDEditField.Value); Subject = '';
            else; Subject = strcat(app.ParticipantIDEditField.Value, '_'); end
            app.FilenameEditField.Value = sprintf(strcat(Subject, app.pers.date, Leader));
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % Get focus back to the main figure
            focus(app.UIFigure);

            % Update user interface
            enableButtons_testState(app);

            % If continuous noise, wait to enable keyboard shortcut
            if app.data.Settings.MaskerSettings == "Continuous"
                app.pers.scoringAvailableFlag = false;
            end

            % Reset iter struct
            app.iter = [];

            % Reset variables for storing results
            temp = app.data.Settings;
            app.data = [];
            app.data.Settings = temp;

            % LABW add preproc defaults
            app.data.Settings.preproc = preproc_defaults;

            % Re-initialize/reset counters and session-specific data in pers
            app.pers.playingFlag = false;
            app.pers.maskerPlayingFlag = false;
            app.pers.maskerPlayingIdx = [];
            app.pers.sentIdxInBlock = 0; % current sentence
            app.pers.sentIdxInSession = 0; % initializing line for saving results

            % (re)load randomization file with original values (if existing)
            if isfield(app.data.Settings, 'UploadedCSV')
                fullfilename = app.data.Settings.UploadedCSV;
                opts = detectImportOptions(fullfilename);
                opts.Encoding = 'UTF-8';
                opts.VariableNamingRule = 'Preserve';
                opts = setvartype(opts,opts.VariableNames=="ListNumber",'string');
                app.pers.randomizationFile = readtable(fullfilename, opts);  % uploaded csv with test info
                app.StartingBlockDropDown.Items = cellstr(num2str(unique(app.pers.randomizationFile.Block, 'stable')));
            end

            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab
                app.pers.blockIdxInSession = 1;
                app.pers.blockTotal = 1;
            else
                app.pers.blockIdxInSession = double(string(app.StartingBlockDropDown.Value)); % current list from dropdown
                app.pers.blockTotal = length(unique(app.pers.randomizationFile.Block, 'stable'));

                if app.SRTInQuietEditField.Enable == "on"

                    % Save value
                    app.data.Settings.InputSRTInQuiet = app.SRTInQuietEditField.Value;

                end

            end
            app.pers.equalizationFilter = [];
            app.pers.audiograms = [];
            app.pers.hearingLossCompensation = [];
            app.pers.nextSignalToNoise = [];
            app.pers.adaptiveTrackPhase = [];

            % Erase data in progress plots and make visible
            cla(app.SentIdxVsSNR);
            cla(app.SNRVsPercent);
            app.ProgressPanel.Title = 'Progress';
            app.SentIdxVsSNR.Visible = "on";
            app.SNRVsPercent.Visible = "on";

            % Update x- and y-axis
            set(app.SentIdxVsSNR,'XLim',[0 21]);
            app.SentIdxVsSNR.XTick = (1:20);
            set(app.SNRVsPercent,'YLim',[-10 110]);

            % Make result filename (i.e., add suffix if necessary)
            app.pers.date = datestr(now,'yyyy_mm_dd');
            TestInfoChanged(app, event);
            app.pers.resultName = app.FilenameEditField.Value;
            app.pers.resultFullfile = fullfile(app.pers.resultDir, [app.pers.resultName, '.mat']);
            newName = app.pers.resultName;
            for i = 1:30
                if isfile(app.pers.resultFullfile)
                    newName = strcat(app.pers.resultName, '.', num2str(i));
                    app.pers.resultFullfile = fullfile(app.pers.resultDir, [newName, '.mat']);
                else
                    break;
                end
            end
            if strcmp(app.pers.resultName,newName) == 0
                app.pers.resultName = newName;
            end

            % Write initial relevant info
            app.data.Settings.HearingLossCompensation = app.pers.hearingLossCompensation; %#ok<ADPROP>
            app.data.Settings.EqualizationFilter = app.pers.equalizationFilter; %#ok<ADPROP>
            app.data.Settings.Tester = app.TesterIDEditField.Value; %#ok<ADPROP>
            app.data.Settings.Participant = app.ParticipantIDEditField.Value; %#ok<ADPROP>
            app.data.Settings.Date = app.pers.date; %#ok<ADPROP>
            app.data.Settings.UploadedPreset = app.pers.UploadedPreset;
            app.data.Settings.UIGain = app.GaindBSpinner.Value; % read current value from UI

            % Initialize audio playback objects
            initializePlaybackObjects(app);

            % Set up visual component, if relevant
            if app.data.Settings.PresentationMode == "Audio-visual"
                setupPsychToolbox(app);
            end

            % Update file locations and names
            updateInfoForNextSent(app);

            % Start relevant signal, depending on the noise mode
            if app.data.Settings.MaskerSettings == "Intermittent"

                % Load, mix, and play the sentence
                loadMixAndPlaySentence(app);

            elseif app.data.Settings.MaskerSettings == "Continuous"

                % Inactivate the scoring panel temporarily
                for button = 1:app.pers.totButtons
                    app.(sprintf('W%iButton', button)).Enable = "off";
                end
                app.MarkallButton.Enable = "off";
                app.ContinueButton.Enable = "off";
                app.ContinueButton.Visible = "off";

                % Enable the play button and make it visible
                app.PlayButton.Visible = "on";
                app.PlayButton.Enable = "on";

                % Load masker
                loadMasker(app);

                % Start continous masker
                if strcmp(app.data.Settings.PlaybackMethod,'Playrec')

                    % Send signal via playrec
                    playrec('play',app.pers.maskerSignal,app.data.Settings.PlaybackChannelsMasker);

                elseif strcmp(app.data.Settings.PlaybackMethod,'SoundMexPro')

                    % Send signal via SoundMexPro
                    listOfChannels = unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]);
                    [~,idxFilter] = ismember(app.data.Settings.PlaybackChannelsMasker,listOfChannels);
                    soundmexpro('loadmem', ...
                        'data',app.pers.maskerSignal, ...
                        'track',numel(listOfChannels)+idxFilter-1, ...
                        'loopcount',1, ...
                        'ramplen',2*app.data.Settings.Fs ... % NOTE extra ramp applied here that is not applied equally across all playback methods
                        );
                    soundmexpro('start','length',0);

                else % AudioToolbox

                    % Start AudioToolbox-based continuous playback
                    playContinuousMaskerAndMixOnTheFly(app);

                end

            end

        end

        % Value changed function: MarkallButton
        function MarkallButtonValueChanged(app, event)

            % Get focus back to the main figure
            focus(app.UIFigure);

            value = app.MarkallButton.Value;
            for i = 1:app.pers.totButtons
                if app.(sprintf('W%iButton', i)).Visible == "on"
                    if (value == 1 && app.data.Settings.ScoringType ~= "Keyword-based") ||...
                            (value == 1 && app.data.Settings.ScoringType == "Keyword-based" && any(app.iter.KeywordsButtons == i))
                        app.(sprintf('W%iButton', i)).Value = 1;
                    else
                        app.(sprintf('W%iButton', i)).Value = 0;
                    end
                end
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)

            % Get focus back to the main figure
            focus(app.UIFigure);

            % Disable Continue Button to avoid double-pressing
            app.ContinueButton.Enable = "off";

            % Save sentence-level results
            saveSentenceLevelPartialResultsInFile(app);

            % Calculate score estimates, the next SNR, and stopping criteria, if relevant
            if app.data.Settings.ResultType == "Speech reception threshold"
                switch app.data.Settings.AdaptiveMethod
                    case 'Standard'
                        criteriaMet = adjustSNRStandardMethod(app);
                    case 'Experimental'
                        criteriaMet = adjustSNRExperimentalMethod(app);
                end
            else
                app.iter.BlockScore = mean(app.data.SentenceInfo.("Sentence score")(...
                    app.data.SentenceInfo.Block==app.pers.blockIdxInSession));
                app.iter.BlockVariability= std(app.data.SentenceInfo.("Sentence score")(...
                    app.data.SentenceInfo.Block==app.pers.blockIdxInSession));
                criteriaMet = false;
            end

            % Save block-level results
            saveBlockLevelPartialResultsInFile(app);

            % Update progress plots
            updateProgressPlots(app);

            % Proceed onto the next relevant step
            if app.data.Settings.ResultType == "Speech reception threshold" && ...
                    (app.pers.sentIdxInBlock == 1) && ...
                    app.iter.SentenceScore < 100 % repeat first sentence until correct

                % Load, mix, and play the next sentence
                loadMixAndPlaySentence(app);

            elseif (app.pers.sentIdxInBlock < app.iter.sentTotalInBlock) && ~criteriaMet % go to next sentence

                % Update file locations/names
                updateInfoForNextSent(app);

                % Load, mix, and play the next sentence
                loadMixAndPlaySentence(app);

            else % block complete

                % Stop continuous masker, if relevant
                if app.data.Settings.MaskerSettings == "Continuous"

                    if app.data.Settings.PlaybackMethod == "Playrec"

                        delPage(app.iter.maskerSignal); % stop playrec

                    elseif app.data.Settings.PlaybackMethod == "SoundMexPro"

                        soundmexpro('stop'); % stop SoundMexPro

                    end

                end

                % Release audio and video objects
                releaseAudioObjs(app);
                if app.data.Settings.PresentationMode == "Audio-visual"
                    sca;
                end

                % Save results for list
                saveFinalResultsInFile(app);

                if app.pers.blockIdxInSession < app.pers.blockTotal % prepare for next block

                    % Enable next button and updat instructions
                    app.ContinueButton.Enable = "off";
                    drawnow; pause(0.05);
                    app.NextButton.Visible = "on";
                    app.NextButton.Enable = "on";
                    app.GaindBSpinner.Enable = "on";
                    app.iter.SentenceScore = [];
                    app.iter.BlockScore = NaN;
                    app.iter.BlockVariability = NaN;
                    app.Instructions.Text = ['Block ', num2str(app.pers.blockIdxInSession), ' done'];

                    % Make all buttons invisible
                    for button = 1:app.pers.totButtons
                        app.(sprintf('W%iButton', button)).Visible = "off";
                    end
                    app.PauseButton.Enable = "off";
                    app.MarkallButton.Visible = "off";

                else % session complete

                    disableButtons_initialState(app);
                    drawnow; pause(0.05);
                    app.Instructions.Text = 'Done';

                end

            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            % Stop continuous masker, if relevant
            if app.data.Settings.MaskerSettings == "Continuous"

                if app.data.Settings.PlaybackMethod == "Playrec"

                    delPage(app.iter.maskerSignal); % stop playrec

                elseif app.data.Settings.PlaybackMethod == "SoundMexPro"

                    soundmexpro('stop'); % stop SoundMexPro

                end

            end

            % Release audio and video objects
            releaseAudioObjs(app);
            if app.data.Settings.PresentationMode == "Audio-visual"
                sca;
            end

            % Disable buttons
            disableButtons_initialState(app);
            app.Instructions.Text = 'Test terminated';

            % Save results for list/end of test
            saveFinalResultsInFile(app);

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)

            % Get focus back to the main figure
            focus(app.UIFigure);

            if app.PauseButton.Value == 1 % Pausing

                % Pause continuous masker, if relevant
                if app.data.Settings.MaskerSettings == "Continuous"

                    if app.data.Settings.PlaybackMethod == "Playrec"

                        pause(app.pers.maskerSignal);

                    elseif app.data.Settings.PlaybackMethod == "SoundMexPro"

                        soundmexpro('pause','value',1); % pause SoundMexPro

                    else % AudioToolbox

                        app.pers.playingFlag = false;
                        app.pers.maskerPlayingFlag = false;
                        releaseAudioObjs(app);

                    end

                end

                % Save state of buttons while updating the interface
                app.Instructions.Text = 'Test paused';
                app.iter.activeButtons = zeros(1, app.pers.totButtons);
                for button = 1:app.pers.totButtons
                    if app.(sprintf('W%iButton', button)).Visible == "on"
                        app.iter.activeButtons(button) = 1;
                    end
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end
                app.ContinueButton.Visible = "off";
                app.PlayButton.Visible = "off";
                app.MarkallButton.Visible = "off";

            else % Resume

                % Update interface
                for button = 1:app.pers.totButtons
                    if app.iter.activeButtons(button) == 1
                        app.(sprintf('W%iButton', button)).Visible = "on";
                    end
                end
                app.MarkallButton.Visible = "on";
                app.Instructions.Text = 'Mark correctly repeated words';
                app.Instructions.Visible = "on";

                % Resume the continuous masker, if relevant
                if app.data.Settings.MaskerSettings == "Continuous"

                    % Re-enable play/continue button
                    if app.PlayButton.Enable == "on"
                        app.PlayButton.Visible = "on";
                    else
                        app.ContinueButton.Visible = "on";
                    end

                    % Re-start the background noise
                    if app.data.Settings.PlaybackMethod == "Playrec"

                        pause(app.pers.maskerSignal);

                    elseif app.data.Settings.PlaybackMethod == "SoundMexPro"

                        soundmexpro('pause','value',0); % unpause SoundMexPro

                    else % AudioToolbox

                        app.pers.maskerPlayingFlag = true;
                        playContinuousMaskerAndMixOnTheFly(app);

                        % Indicate to playContinuousMaskerAndMixOnTheFly that the target is ready
                        pause(0.5); % wait 500 ms
                        app.pers.playingFlag = true;
                        app.iter.playingIdx = 1;

                    end

                else

                    % Re-enable continue button
                    app.ContinueButton.Enable = "off";
                    app.ContinueButton.Visible = "on";

                    % Load, mix, and play the sentence again
                    loadMixAndPlaySentence(app);

                end

            end

        end

        % Value changed function: W1Button, W2Button, W3Button, W4Button, 
        % ...and 4 other components
        function wordSelected(app, event)

            % Get focus back to the main figure
            focus(app.UIFigure);

            values = zeros(1,app.pers.totButtons);
            for i = 1:app.pers.totButtons
                values(i) =  app.(sprintf('W%iButton', i)).Value;
            end
            if any(values == 0)
                app.MarkallButton.Value = 0;
            end

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,pathname] = uigetfile(fullfile(pwd,'files','*.csv'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                try
                    % Load and read from csv file
                    fullfilename = fullfile(pathname,filename);
                    opts = detectImportOptions(fullfilename);
                    opts.Encoding = 'UTF-8';
                    opts.VariableNamingRule = 'Preserve';
                    opts = setvartype(opts,opts.VariableNames=="ListNumber",'string');
                    app.pers.randomizationFile = readtable(fullfilename, opts);  % uploaded csv with test info
                    app.data.Settings.UploadedCSV = fullfilename;

                    % Showing list options in menu
                    app.UploadedCSVCheckBox.Value = 1;
                    app.StartingblockDropDownLabel.Visible = "on";
                    app.StartingBlockDropDown.Visible = "on";
                    app.StartingBlockDropDown.Items = cellstr(num2str(unique(app.pers.randomizationFile.Block, 'stable')));

                    % Updating UI text field with shortened path
                    pathparts = strsplit(fullfilename,filesep);
                    shortedPath = fullfile('...',pathparts(end));
                    app.CSVFileEditField.Value = string(shortedPath);

                    drawnow;
                    figure(app.UIFigure);

                catch
                    figure(app.UIFigure);
                    app.CSVFileEditField.Value = '';
                    app.UploadedCSVCheckBox.Value = 0;
                    app.StartingblockDropDownLabel.Visible = "off";
                    app.StartingBlockDropDown.Visible = "off";
                    disableButtons_initialState(app);
                    error('Invalid CSV file. Please upload a file with the right format.');
                end

                % Get focus back to the main figure
                focus(app.UIFigure);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)

            % Update user interface
            app.NextButton.Visible = "off";
            app.NextButton.Enable = "off";
            enableButtons_testState(app);

            % Erase data in progress plots
            cla(app.SentIdxVsSNR);
            cla(app.SNRVsPercent);
            app.ProgressPanel.Title = 'Progress';

            % Update counters
            app.pers.blockIdxInSession = app.pers.blockIdxInSession + 1;
            app.pers.sentIdxInBlock = 0;

            % Update file locations and names
            updateInfoForNextSent(app);

            % read UI gain - may be changed between blocks
            app.data.Settings.UIGain = app.GaindBSpinner.Value; % read current value from UI

            % Start relevant signal, depending on the noise mode
            if app.data.Settings.MaskerSettings == "Intermittent"

                % Load, mix, and play the sentence
                loadMixAndPlaySentence(app);

            elseif app.data.Settings.MaskerSettings == "Continuous"

                % Inactivate the scoring panel temporarily
                for button = 1:app.pers.totButtons
                    app.(sprintf('W%iButton', button)).Enable = "off";
                end
                app.MarkallButton.Enable = "off";
                app.ContinueButton.Enable = "off";
                app.ContinueButton.Visible = "off";

                % Enable the play button and make it visible
                app.PlayButton.Visible = "on";
                app.PlayButton.Enable = "on";

                % Load masker
                loadMasker(app);

                % Start continous masker
                if app.data.Settings.PlaybackMethod == "Playrec"

                    % Send signal via playrec
                    playrec('play',app.pers.maskerSignal,app.data.Settings.PlaybackChannelsMasker);

                elseif app.data.Settings.PlaybackMethod == "SoundMexPro"

                    % Send signal via SoundMexPro
                    listOfChannels = unique([app.data.Settings.PlaybackChannelsTarget app.data.Settings.PlaybackChannelsMasker]);
                    [~,idxFilter] = ismember(app.data.Settings.PlaybackChannelsMasker,listOfChannels);
                    soundmexpro('loadmem', ...
                        'data',app.pers.maskerSignal, ...
                        'track',numel(listOfChannels)+idxFilter-1, ...
                        'loopcount',1 ...
                        );
                    soundmexpro('start','length',0);

                else % AudioToolbox

                    % Start AudioToolbox-based continuous playback
                    playContinuousMaskerAndMixOnTheFly(app);

                end

            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Do you want to close the window?', 'Confirm');
            if strcmpi(selection,'OK')

                % release audio objects
                releaseAudioObjs(app);

                % Save results for list/end of test
                saveFinalResultsInFile(app);

                % Delete the app
                if ~isempty(app.SettingsApp)
                    delete(app.SettingsApp)
                end
                delete(app);

            end
        end

        % Button pushed function: LoadPresetButton
        function LoadPresetButtonPushed(app, event)

            [filename,pathname] = uigetfile(fullfile(app.pers.presetDir,'*.mat'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return
            else
                % loading variable
                fullfilename = fullfile(pathname,filename);
                load(fullfilename);
                app.data.Settings = Settings;
                [~,f,ext]=fileparts(fullfilename);

                % if default, set audio and video path relative to pwd
                if strcat(f,ext) == "DAST_default_settings.mat"
                    % Audio and video directories
                    app.data.Settings.CorporaPath = pwd;
                end
                filename=fullfile(f);
                app.UploadedPresetEditField.Value = filename;
                app.pers.UploadedPreset = app.UploadedPresetEditField.Value;

                drawnow;
                figure(app.UIFigure);

            end

            % Get focus back to the main figure
            focus(app.UIFigure);
        end

        % Value changed function: StartingTargetLevelEditField
        function StartingTargetLevelEditFieldValueChanged(app, event)

        end

        % Value changed function: StartingMaskerLevelEditField
        function StartingMaskerLevelEditFieldValueChanged(app, event)

        end

        % Value changed function: ListTypeDropDown
        function ListTypeDropDownValueChanged(app, event)

            if ~isempty(app.ListTypeDropDown.Value)
                if ~isfield(app.pers,app.ListTypeDropDown.Value) % first time loading

                    % Load file
                    loadListInfo(app,app.CorpusDropDown.Value,app.ListTypeDropDown.Value);

                    % Populate List Number dropdown accordingly
                    listNumbers = unique(app.pers.(['List_' app.ListTypeDropDown.Value]).List, 'stable');
                    listNumberTypes = cellstr(["Random list"; listNumbers]);
                    app.ListNumberDropDown.Items = listNumberTypes;
                    app.ListNumberDropDown.Value = listNumberTypes{strcmp(listNumberTypes,"1")}; % default to 1

                else % csv already loaded

                    % Populate List Number dropdown accordingly
                    listNumbers = unique(app.pers.(['List_' app.ListTypeDropDown.Value]).List, 'stable');
                    listNumberTypes = cellstr(["Random list"; listNumbers]);
                    if ismember(app.ListNumberDropDown.Value,listNumberTypes)
                        app.ListNumberDropDown.Items = listNumberTypes;
                    else
                        app.ListNumberDropDown.Value = listNumberTypes{strcmp(listNumberTypes,"1")}; % default to 1
                        app.ListNumberDropDown.Items = listNumberTypes;
                    end

                end
            end

            checkForMissingValuesInQuickStartMode(app);

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            % get pressed key
            key = event.Key;

            % To enable (de)selecting keywords and continue with keyboard shortcuts
            if app.data.Settings.ScoringType == "Keyword-based" &&...
                    app.pers.scoringAvailableFlag == true

                switch key

                    % First keyword button
                    case {'1','numpad1'}
                        if app.(sprintf('W%iButton', app.iter.KeywordsButtons(1))).Value == 0
                            app.(sprintf('W%iButton', app.iter.KeywordsButtons(1))).Value = 1;
                        else; app.(sprintf('W%iButton', app.iter.KeywordsButtons(1))).Value = 0;
                        end
                        app.MarkallButton.Value = 0;

                        % Second keyword button
                    case {'2','numpad2'}
                        if app.(sprintf('W%iButton', app.iter.KeywordsButtons(2))).Value == 0
                            app.(sprintf('W%iButton', app.iter.KeywordsButtons(2))).Value = 1;
                        else; app.(sprintf('W%iButton', app.iter.KeywordsButtons(2))).Value = 0;
                        end
                        app.MarkallButton.Value = 0;

                        % Third keyword button
                    case {'3','numpad3'}
                        if app.(sprintf('W%iButton', app.iter.KeywordsButtons(3))).Value == 0
                            app.(sprintf('W%iButton', app.iter.KeywordsButtons(3))).Value = 1;
                        else; app.(sprintf('W%iButton', app.iter.KeywordsButtons(3))).Value = 0;
                        end
                        app.MarkallButton.Value = 0;

                        % To accept and continue with next sentence
                    case 'return'
                        if app.ContinueButton.Enable == "on"
                            app.ContinueButtonPushed(app)
                        end
                end
            end

        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)

            % Get focus back to the main figure
            focus(app.UIFigure);

            drawnow;
            figure(app.UIFigure);

            % Call Settings App and pass variables
            app.SettingsApp = DASTsettings(app, app.data, app.pers);

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            % Get focus back to the main figure
            focus(app.UIFigure);

            % Initialize flag for scoring (to enable keyboard shortcuts)
            app.pers.scoringAvailableFlag = true;

            % Disable and make invisible the play button
            app.PlayButton.Enable = "off";
            app.PlayButton.Visible = "off";

            % Re-activate the scoring panel
            for button = 1:app.pers.totButtons
                app.(sprintf('W%iButton', button)).Enable = "on";
            end
            app.MarkallButton.Enable = "on";
            app.ContinueButton.Enable = "on";
            app.ContinueButton.Visible = "on";

            % Disable non-keyword buttons (when keyword-based scoring)
            if app.data.Settings.ScoringType == "Keyword-based"
                for m = 1:app.pers.totButtons
                    if ~any(app.iter.KeywordsButtons == m)
                        app.(sprintf('W%iButton', m)).Enable = "off";
                    end
                end
            end

            % Load, mix, and play the sentence
            loadMixAndPlaySentence(app);

        end

        % Value changed function: CorpusDropDown
        function CorpusDropDownValueChanged(app, event)

            if ~isempty(app.CorpusDropDown.Value)

                % Populate dropdowns
                TargetDropDownOpening(app);
                MaskerDropDownOpening(app);
                ListTypeDropDownOpening(app);
                ListTypeDropDownValueChanged(app);

            end

            checkForMissingValuesInQuickStartMode(app);

        end

        % Drop down opening function: CorpusDropDown
        function CorpusDropDownOpening(app, event)

            % Read in corpus files
            corpus_files = dir(fullfile(app.data.Settings.CorporaPath,'Corpus_*','metadata','Corpus_*.csv'));
            if numel(corpus_files) > 0
                app.iter.corpusTypes = cell(numel(corpus_files),1);
                for cc = 1:numel(corpus_files)
                    app.iter.corpusTypes{cc} = corpus_files(cc).name(8:end-4);
                end
            else
                app.iter.corpusTypes{1} = '';
            end
            app.CorpusDropDown.Items = app.iter.corpusTypes;


        end

        % Drop down opening function: TargetDropDown
        function TargetDropDownOpening(app, event)

            % Read in target files
            target_files = dir(fullfile(app.data.Settings.CorporaPath,...
                ['Corpus_' app.CorpusDropDown.Value],'speech'));
            target_files = target_files(~startsWith({target_files.name}, {'.'}));
            if numel(target_files) > 0
                app.iter.targetTypes = cell(numel(target_files),1);
                for tt = 1:numel(target_files)
                    app.iter.targetTypes{tt} = target_files(tt).name;
                end
            else
                app.iter.targetTypes{1} = '';
            end
            app.TargetDropDown.Items = app.iter.targetTypes;

        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)

            checkForMissingValuesInQuickStartMode(app)

        end

        % Value changed function: MaskerDropDown
        function MaskerDropDownValueChanged(app, event)

            checkForMissingValuesInQuickStartMode(app)

        end

        % Drop down opening function: MaskerDropDown
        function MaskerDropDownOpening(app, event)

            % Read in masker files
            masker_files = dir(fullfile(app.data.Settings.CorporaPath,...
                ['Corpus_' app.CorpusDropDown.Value],'noise','*.wav'));
            masker_files = masker_files(~startsWith({masker_files.name}, {'.'}));
            if numel(masker_files) > 0
                app.iter.maskerTypes = cell(numel(masker_files),1);
                for mm = 1:numel(masker_files)
                    app.iter.maskerTypes{mm} = masker_files(mm).name(1:end-4);
                end
            else
                app.iter.maskerTypes{1} = '';
            end
            app.MaskerDropDown.Items = app.iter.maskerTypes;

        end

        % Drop down opening function: ListTypeDropDown
        function ListTypeDropDownOpening(app, event)

            % Read in List(s)
            list_files = dir(fullfile(app.data.Settings.CorporaPath,...
                ['Corpus_' app.CorpusDropDown.Value],'metadata','List_*.csv'));
            if numel(list_files) > 0
                app.iter.listTypes = cell(numel(list_files),1);
                for ll = 1:numel(list_files)
                    app.iter.listTypes{ll} = list_files(ll).name(6:end-4);
                end
            else
                app.iter.listTypes{1} = '';
            end
            app.ListTypeDropDown.Items = app.iter.listTypes;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.BasicInformationPanel = uipanel(app.UIFigure);
            app.BasicInformationPanel.Title = 'Basic information';
            app.TesterIDEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.TesterIDEditFieldLabel.Text = 'Tester ID';
            app.TesterIDEditField = uieditfield(app.BasicInformationPanel, 'text');
            app.TesterIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.ParticipantIDEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.BasicInformationPanel, 'text');
            app.ParticipantIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.BasicInformationPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditField.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Tag = 'SettingsPanel';
            app.AudioSettingsTabs = uitabgroup(app.Panel);
            app.QuickstartTab = uitab(app.AudioSettingsTabs);
            app.QuickstartTab.Title = 'Quick start';
            app.dBSPLLabel = uilabel(app.QuickstartTab);
            app.dBSPLLabel.Text = 'dB SPL';
            app.dBSPLLabel_2 = uilabel(app.QuickstartTab);
            app.dBSPLLabel_2.Text = 'dB SPL';
            app.TargetDropDownLabel = uilabel(app.QuickstartTab);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.QuickstartTab);
            app.TargetDropDown.Items = {};
            app.TargetDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TargetDropDownOpening, true);
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.TargetDropDown.Value = {};
            app.ListnumberDropDownLabel = uilabel(app.QuickstartTab);
            app.ListnumberDropDownLabel.Text = 'List number';
            app.ListNumberDropDown = uidropdown(app.QuickstartTab);
            app.ListNumberDropDown.Items = {};
            app.ListNumberDropDown.Value = {};
            app.MaskerLabel = uilabel(app.QuickstartTab);
            app.MaskerLabel.Text = 'Masker';
            app.MaskerDropDown = uidropdown(app.QuickstartTab);
            app.MaskerDropDown.Items = {};
            app.MaskerDropDown.DropDownOpeningFcn = createCallbackFcn(app, @MaskerDropDownOpening, true);
            app.MaskerDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskerDropDownValueChanged, true);
            app.MaskerDropDown.Value = {};
            app.TargetlevelLabel = uilabel(app.QuickstartTab);
            app.TargetlevelLabel.Text = 'Starting target level';
            app.StartingTargetLevelEditField = uieditfield(app.QuickstartTab, 'numeric');
            app.StartingTargetLevelEditField.Limits = [0 Inf];
            app.StartingTargetLevelEditField.ValueChangedFcn = createCallbackFcn(app, @StartingTargetLevelEditFieldValueChanged, true);
            app.StartingTargetLevelEditField.Value = 65;
            app.StartingmaskerlevelLabel = uilabel(app.QuickstartTab);
            app.StartingmaskerlevelLabel.Text = 'Starting masker level';
            app.StartingMaskerLevelEditField = uieditfield(app.QuickstartTab, 'numeric');
            app.StartingMaskerLevelEditField.Limits = [0 Inf];
            app.StartingMaskerLevelEditField.ValueChangedFcn = createCallbackFcn(app, @StartingMaskerLevelEditFieldValueChanged, true);
            app.StartingMaskerLevelEditField.Value = 65;
            app.ListtypeDropDownLabel = uilabel(app.QuickstartTab);
            app.ListtypeDropDownLabel.Text = 'List type';
            app.ListTypeDropDown = uidropdown(app.QuickstartTab);
            app.ListTypeDropDown.Items = {};
            app.ListTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ListTypeDropDownOpening, true);
            app.ListTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ListTypeDropDownValueChanged, true);
            app.ListTypeDropDown.Value = {};
            app.CorpusDropDownLabel = uilabel(app.QuickstartTab);
            app.CorpusDropDownLabel.Text = 'Corpus';
            app.CorpusDropDown = uidropdown(app.QuickstartTab);
            app.CorpusDropDown.Items = {};
            app.CorpusDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CorpusDropDownOpening, true);
            app.CorpusDropDown.ValueChangedFcn = createCallbackFcn(app, @CorpusDropDownValueChanged, true);
            app.CorpusDropDown.Value = {};
            app.ModeDropDown_2Label = uilabel(app.QuickstartTab);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.ModeDropDownInQuickStartTab = uidropdown(app.QuickstartTab);
            app.ModeDropDownInQuickStartTab.Items = {'Unaided', 'Aided'};
            app.ModeDropDownInQuickStartTab.Value = 'Unaided';
            app.AdvancedTab = uitab(app.AudioSettingsTabs);
            app.AdvancedTab.Title = 'Advanced';
            app.UploadButton = uibutton(app.AdvancedTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.CSVfileEditFieldLabel = uilabel(app.AdvancedTab);
            app.CSVfileEditFieldLabel.Text = 'CSV file';
            app.CSVFileEditField = uieditfield(app.AdvancedTab, 'text');
            app.CSVFileEditField.Editable = 'off';
            app.UploadedCSVCheckBox = uicheckbox(app.AdvancedTab);
            app.UploadedCSVCheckBox.Enable = 'off';
            app.UploadedCSVCheckBox.Text = 'CheckUploadedCSV';
            app.StartingblockDropDownLabel = uilabel(app.AdvancedTab);
            app.StartingblockDropDownLabel.Text = 'Starting block';
            app.StartingBlockDropDown = uidropdown(app.AdvancedTab);
            app.StartingBlockDropDown.Items = {};
            app.StartingBlockDropDown.Value = {};
            app.SRTinquietEditFieldLabel = uilabel(app.AdvancedTab);
            app.SRTinquietEditFieldLabel.Text = 'SRT in quiet';
            app.SRTInQuietEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.SRTInQuietEditField.Limits = [0 70];
            app.SRTInQuietEditField.Tooltip = {'Insert the SRT in quiet (speech recognition threshold) value in order to adjust the masker noise level. If, for example, SRT <= 45 dB, the noise level remains at default 65 dB. If SRT > 45 dB, the noise level is fixed at SRT + 20 dB, and the target level is adapted according to the initial SNR.'};
            app.dBLabel_2 = uilabel(app.AdvancedTab);
            app.dBLabel_2.Text = 'dB';
            app.ModeDropDownLabel = uilabel(app.AdvancedTab);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDownInAdvanced = uidropdown(app.AdvancedTab);
            app.ModeDropDownInAdvanced.Items = {'Unaided', 'Aided'};
            app.ModeDropDownInAdvanced.Value = 'Unaided';
            app.ScoringPanel = uipanel(app.UIFigure);
            app.ScoringPanel.Title = 'Scoring';
            app.ContinueButton = uibutton(app.ScoringPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.MarkallButton = uibutton(app.ScoringPanel, 'state');
            app.MarkallButton.ValueChangedFcn = createCallbackFcn(app, @MarkallButtonValueChanged, true);
            app.MarkallButton.Text = 'Mark all';
            app.PlayButton = uibutton(app.ScoringPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.W1Button = uibutton(app.ScoringPanel, 'state');
            app.W1Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W1Button.Text = 'W1';
            app.W2Button = uibutton(app.ScoringPanel, 'state');
            app.W2Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W2Button.Text = 'W2';
            app.W3Button = uibutton(app.ScoringPanel, 'state');
            app.W3Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W3Button.Text = 'W3';
            app.W4Button = uibutton(app.ScoringPanel, 'state');
            app.W4Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W4Button.Text = 'W4';
            app.W5Button = uibutton(app.ScoringPanel, 'state');
            app.W5Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W5Button.Text = 'W5';
            app.W6Button = uibutton(app.ScoringPanel, 'state');
            app.W6Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W6Button.Text = 'W6';
            app.W7Button = uibutton(app.ScoringPanel, 'state');
            app.W7Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W7Button.Text = 'W7';
            app.W8Button = uibutton(app.ScoringPanel, 'state');
            app.W8Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W8Button.Text = 'W8';
            app.W9Button = uibutton(app.ScoringPanel, 'state');
            app.W9Button.Text = 'W9';
            app.W10Button = uibutton(app.ScoringPanel, 'state');
            app.W10Button.Text = 'W10';
            app.W11Button = uibutton(app.ScoringPanel, 'state');
            app.W11Button.Text = 'W11';
            app.W12Button = uibutton(app.ScoringPanel, 'state');
            app.W12Button.Text = 'W12';
            app.CurrentSent = uilabel(app.ScoringPanel);
            app.CurrentSent.Tag = 'currentSent';
            app.CurrentSent.Text = 'Current Sent';
            app.W13Button = uibutton(app.ScoringPanel, 'state');
            app.W13Button.Text = 'W13';
            app.W14Button = uibutton(app.ScoringPanel, 'state');
            app.W14Button.Text = 'W14';
            app.W15Button = uibutton(app.ScoringPanel, 'state');
            app.W15Button.Text = 'W15';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DASTpreprocHLCM1Label = uilabel(app.UIFigure);
            app.DASTpreprocHLCM1Label.Text = 'DAST preproc - HLCM.1';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ProgressPanel = uipanel(app.UIFigure);
            app.ProgressPanel.Title = 'Progress';
            app.SNRVsPercent = uiaxes(app.ProgressPanel);
            xlabel(app.SNRVsPercent, 'SNR')
            ylabel(app.SNRVsPercent, '%')
            app.SNRVsPercent.Visible = 'off';
            app.SentIdxVsSNR = uiaxes(app.ProgressPanel);
            xlabel(app.SentIdxVsSNR, 'Sentence')
            ylabel(app.SentIdxVsSNR, 'SNR')
            app.SentIdxVsSNR.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.SentIdxVsSNR.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.SentIdxVsSNR.Visible = 'off';
            app.CurrentBlock = uilabel(app.ProgressPanel);
            app.CurrentBlock.Tag = 'currentBlock';
            app.CurrentBlock.Text = 'Current Block';
            app.Instructions = uilabel(app.UIFigure);
            app.Instructions.Visible = 'off';
            app.Instructions.Text = 'Mark correctly repeated words';
            app.SettingsButton = uibutton(app.UIFigure, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.LoadPresetButton = uibutton(app.UIFigure, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButtonPushed, true);
            app.LoadPresetButton.Text = 'Load preset';
            app.UploadedPresetEditField = uieditfield(app.UIFigure, 'text');
            app.UploadedPresetEditField.Editable = 'off';
            app.GaindBSpinnerLabel = uilabel(app.UIFigure);
            app.GaindBSpinnerLabel.Text = 'Gain dB';
            app.GaindBSpinner = uispinner(app.UIFigure);
            app.GaindBSpinner.Limits = [-20 0];
            app.GaindBSpinner.RoundFractionalValues = 'on';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end